classdef ImagingSimulationInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartSimulationButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        ModulationmethodDropDownLabel   matlab.ui.control.Label
        ModulationmethodDropDown        matlab.ui.control.DropDown
        SamplechoiceDropDownLabel       matlab.ui.control.Label
        SamplechoiceDropDown            matlab.ui.control.DropDown
        PhaseencodingDropDownLabel      matlab.ui.control.Label
        PhaseencodingDropDown           matlab.ui.control.DropDown
        DatacollectionDropDownLabel     matlab.ui.control.Label
        DatacollectionDropDown          matlab.ui.control.DropDown
        UIAxes2                         matlab.ui.control.UIAxes
        InputparametersTab              matlab.ui.container.Tab
        TCusEditFieldLabel              matlab.ui.control.Label
        TCusEditField                   matlab.ui.control.NumericEditField
        PDTusEditFieldLabel             matlab.ui.control.Label
        PDTusEditField                  matlab.ui.control.NumericEditField
        FilterorderSpinnerLabel         matlab.ui.control.Label
        FilterorderSpinner              matlab.ui.control.Spinner
        xpixelsEditFieldLabel           matlab.ui.control.Label
        xpixelsEditField                matlab.ui.control.NumericEditField
        ypixelsLabel                    matlab.ui.control.Label
        ypixelsEditField                matlab.ui.control.NumericEditField
        PixelsizeumEditFieldLabel       matlab.ui.control.Label
        PixelsizeumEditField            matlab.ui.control.NumericEditField
        ProbepowermWEditFieldLabel      matlab.ui.control.Label
        ProbepowermWEditField           matlab.ui.control.NumericEditField
        CarrierpowermWEditFieldLabel    matlab.ui.control.Label
        CarrierpowermWEditField         matlab.ui.control.NumericEditField
        SetupparametersTab              matlab.ui.container.Tab
        CsignalEditFieldLabel           matlab.ui.control.Label
        CsignalEditField                matlab.ui.control.NumericEditField
        CthermalEditFieldLabel          matlab.ui.control.Label
        CthermalEditField               matlab.ui.control.NumericEditField
        CshotEditFieldLabel             matlab.ui.control.Label
        CshotEditField                  matlab.ui.control.NumericEditField
        Beam1Panel                      matlab.ui.container.Panel
        w01xEditFieldLabel              matlab.ui.control.Label
        w01xEditField                   matlab.ui.control.NumericEditField
        w01yEditFieldLabel              matlab.ui.control.Label
        w01yEditField                   matlab.ui.control.NumericEditField
        Beam2Panel                      matlab.ui.container.Panel
        w02xEditFieldLabel              matlab.ui.control.Label
        w02xEditField                   matlab.ui.control.NumericEditField
        w02yEditFieldLabel              matlab.ui.control.Label
        w02yEditField                   matlab.ui.control.NumericEditField
        TextTextArea                    matlab.ui.control.TextArea
        TextTextArea_2                  matlab.ui.control.TextArea
        TextTextArea_3                  matlab.ui.control.TextArea
        TextTextArea_4                  matlab.ui.control.TextArea
        TextTextArea_5                  matlab.ui.control.TextArea
        EquationLabel                   matlab.ui.control.Label
        EffectiveSRSshapePanel          matlab.ui.container.Panel
        wSRSxEditFieldLabel             matlab.ui.control.Label
        wSRSxEditField                  matlab.ui.control.NumericEditField
        wSRSyEditFieldLabel             matlab.ui.control.Label
        wSRSyEditField                  matlab.ui.control.NumericEditField
        SRSisthemultiplicationofthetwobeamsLabel  matlab.ui.control.Label
        ThebeamsareassumedtobefocussedGaussianbeamswithLabel  matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        ChannelsTab                     matlab.ui.container.Tab
        RadiusEditFieldLabel            matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        ChanneldistanceHzEditFieldLabel  matlab.ui.control.Label
        ChanneldistanceHzEditField      matlab.ui.control.NumericEditField
        XsizeumEditFieldLabel           matlab.ui.control.Label
        XsizeumEditField                matlab.ui.control.NumericEditField
        YsizeumEditFieldLabel           matlab.ui.control.Label
        YsizeumEditField                matlab.ui.control.NumericEditField
        ObjectsizeLabel                 matlab.ui.control.Label
        ModulationfrequencyHzEditFieldLabel  matlab.ui.control.Label
        ModulationfrequencyHzEditField  matlab.ui.control.NumericEditField
        BeadsListBoxLabel               matlab.ui.control.Label
        BeadsListBox                    matlab.ui.control.ListBox
        AddbeadButton                   matlab.ui.control.Button
        DeletebeadButton                matlab.ui.control.Button
        ChannelSpinnerLabel             matlab.ui.control.Label
        ChannelSpinner                  matlab.ui.control.Spinner
        XpositionEditFieldLabel         matlab.ui.control.Label
        XpositionEditField              matlab.ui.control.NumericEditField
        YpositionEditFieldLabel         matlab.ui.control.Label
        YpositionEditField              matlab.ui.control.NumericEditField
        NumberofchannelsSpinnerLabel    matlab.ui.control.Label
        NumberofchannelsSpinner         matlab.ui.control.Spinner
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        SampleLabel                     matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        AdvancedsettingsCheckBox        matlab.ui.control.CheckBox
        AdvancedsettingsPanel           matlab.ui.container.Panel
        CarrierpowermWLabel             matlab.ui.control.Label
        Channel1EditFieldLabel          matlab.ui.control.Label
        Channel1EditField               matlab.ui.control.NumericEditField
        Channel2EditFieldLabel          matlab.ui.control.Label
        Channel2EditField               matlab.ui.control.NumericEditField
        Channel3EditFieldLabel          matlab.ui.control.Label
        Channel3EditField               matlab.ui.control.NumericEditField
        Channel4EditFieldLabel          matlab.ui.control.Label
        Channel4EditField               matlab.ui.control.NumericEditField
        Channel5EditFieldLabel          matlab.ui.control.Label
        Channel5EditField               matlab.ui.control.NumericEditField
        Channel6EditFieldLabel          matlab.ui.control.Label
        Channel6EditField               matlab.ui.control.NumericEditField
        ModfrequencyHzLabel             matlab.ui.control.Label
        Freq1EditField                  matlab.ui.control.NumericEditField
        Freq2EditField                  matlab.ui.control.NumericEditField
        Freq3EditField                  matlab.ui.control.NumericEditField
        Freq4EditField                  matlab.ui.control.NumericEditField
        Freq5EditField                  matlab.ui.control.NumericEditField
        Freq6EditField                  matlab.ui.control.NumericEditField
        NumberofchannelsSpinner_2Label  matlab.ui.control.Label
        NumberofchannelsSpinner_2       matlab.ui.control.Spinner
        UITable                         matlab.ui.control.Table
        MaterialLabel                   matlab.ui.control.Label
        ChannelLabel                    matlab.ui.control.Label
        Phase1DropDown                  matlab.ui.control.DropDown
        Phase2DropDown                  matlab.ui.control.DropDown
        Phase3DropDown                  matlab.ui.control.DropDown
        Phase4DropDown                  matlab.ui.control.DropDown
        Phase5DropDown                  matlab.ui.control.DropDown
        Phase6DropDown                  matlab.ui.control.DropDown
        PhaseLabel                      matlab.ui.control.Label
        FastcarriermodulationHzEditFieldLabel  matlab.ui.control.Label
        FastcarriermodulationHzEditField  matlab.ui.control.NumericEditField
        CalculatingLabel                matlab.ui.control.Label
        ErrormessageLabel               matlab.ui.control.Label
        PreviewLabel                    matlab.ui.control.Label
    end


    properties (Access = private)
        
        % Setup and measurement type
        modulation_method
        phase_encoding
        sample_choice
        Analog % Digital (0, single sample) or Analog (1, averaging) data collection
        
        % Pixel properties
        xpixels % pixels in x direction
        ypixels % pixels in y direction
        pixelsize % size of pixels in um
        
        % Channel properties
        numberofchannels % # of channels
        Channel_Selected % Selected channel for changing object positions
        channel_distance % Distance between channels in Hz
        modulationfrequency % Modulation frequency of channel 1
        
        % Digital RC filter properties
        TC % Filter time constant
        PDT % Pixel dwell time
        filter_order % Filter order
        
        % Beam powers in mW
        pump_power % Probe power
        stokes_power % Carrier power
        
        % Coefficients for SNR behaviour
        Csignal % Signal coefficient
        Cshot % Shot noise coefficient
        Celec % Electronic noise coefficient
        
        % Beam parameters (beam waist and Rayleigh range)
        w01x
        w01y
        w02x
        w02y
        zR1x % Rayleigh range
        zR1y
        zR2x
        zR2y
        
        % Object properties
        sample_id % Incrementing number for sample id
        sample_properties % Sample properties (channel, radius, x, y)
        selected_sample % Currently selected sample
        
        % Advanced settings
        Advanced_settings % Advanced: on or off
        CsignalMatrix % Signal coefficients matrix
        FrequencyMatrix % Frequency for each channel
        Fast_Frequency % Fast modulation frequency
        PowerMatrix % Powers per channel
        PhaseMatrix % Phase of each channel: 0 or pi/2

    end

    methods (Access = private)
    
        function [] = Update_Image(app)
            % This function is called whenever the preview image needs to be updated
            
            % Obtain sample properties
            sample_prop_input = input_sample_func(app);
            
            % Produce image matrix for selected sample type
            if app.sample_choice==0
                
                % 2D bead sample
                matrix=false(app.ypixels,app.xpixels,1,app.numberofchannels);
                
                % Extract sample info from sample properties
                for i=1:app.numberofchannels
                    properties=sample_prop_input{i};
                    if ~isempty(properties)
                        r = properties(2,1,:);
                        x0 = properties(3,1,:);
                        y0 = properties(4,1,:);
                        matrix(:,:,1,i) = sum((((1:1:app.xpixels)*app.pixelsize-x0).^2+((1:1:app.ypixels)'*app.pixelsize-y0).^2)<=r.^2,3)>=1;
                    end
                end
                
                % Calculate matrix for RGB preview image
                channelcolor=permute([1,0,0;0,1,0;0,0,1;1,1,0;1,0,1;0,1,1],[3,4,2,1]);
                imagematrix=sum(matrix(:,:,:,1:app.numberofchannels).*channelcolor(:,:,:,1:app.numberofchannels),4);
            
            else
                
                % 1D sample
                matrix=false(1,app.xpixels,1,app.numberofchannels);
                
                % Extract sample info from sample properties
                for i=1:app.numberofchannels
                    properties=sample_prop_input{i};
                    if ~isempty(properties)
                        r = properties(2,1,:);
                        x0 = properties(3,1,:);
                        matrix(1,:,1,i) = sum(((1:1:app.xpixels)*app.pixelsize>=x0)&((1:1:app.xpixels)*app.pixelsize<=x0+r),3)>=1;
                    end
                end
                
                % Calculate matrix for RGB preview image
                channelcolor=permute([1,0,0;0,1,0;0,0,1;1,1,0;1,0,1;0,1,1],[3,4,2,1]);
                imagematrix=repmat(sum(matrix(:,:,:,1:app.numberofchannels).*channelcolor(:,:,:,1:app.numberofchannels),4),round(app.xpixels/10),1);
                
            end
            
            % Update image
            imshow(imagematrix,'Parent',app.UIAxes);
            
        end
        
        
        function sample_prop_input = input_sample_func(app)
            % Formula to put the sample properties (channel,radius,x,y) in the right form.
            % For each channel, the properties of all its samples are put in the same cell.
            
            sample_prop_input=cell(1,app.numberofchannels);
            for i=1:app.numberofchannels
                sample_prop_input{i}=permute(app.sample_properties(:,app.sample_properties(1,:)==i),[1,3,2]);
            end
        end
        
        function [] = Phase_encoding_Enable(app)
            if app.phase_encoding && app.Advanced_settings
                app.Phase1DropDown.Enable='on';
                app.Phase2DropDown.Enable='on';
                app.Phase3DropDown.Enable='on';
                app.Phase4DropDown.Enable='on';
                app.Phase5DropDown.Enable='on';
                app.Phase6DropDown.Enable='on';
                app.PhaseLabel.Enable='on';
            else
                app.Phase1DropDown.Enable='off';
                app.Phase2DropDown.Enable='off';
                app.Phase3DropDown.Enable='off';
                app.Phase4DropDown.Enable='off';
                app.Phase5DropDown.Enable='off';
                app.Phase6DropDown.Enable='off';
                app.PhaseLabel.Enable='off';
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Set text for the interfaces
            app.TextTextArea.Value = ['(V/mW',char(178),')'];
            app.TextTextArea_2.Value = ['(V/',char(8730),'(mW Hz))'];
            app.TextTextArea_3.Value = ['(V/',char(8730),'Hz)'];
            app.TextTextArea_4.Value = ['in ',char(181),'m'];
            app.TextTextArea_5.Value = ['in ',char(181),'m'];
            app.ObjectsizeLabel.Text = "Radius";
            app.EquationLabel.Text = [char(969),'SRS=',char(969),char(8321),char(969),char(8322),'/',char(8730),'(',char(969),char(8321),char(178),'+',char(969),char(8322),char(178),')'];
            app.ThebeamsareassumedtobefocussedGaussianbeamswithLabel.Text=['The beams are assumed to be focussed Gaussian beams',newline,'with w as the 1/e',char(178),' radius.'];
            
            % Set fields to active or inactive
            app.RadiusEditField.Enable = 'on';
            app.CalculatingLabel.Visible = 'off';
            
            % Set variable starting values
            app.ModulationmethodDropDown.Value = 'Single Carrier modulation';
            app.SamplechoiceDropDown.Value = '2D bead';
            app.PhaseencodingDropDown.Value = 'Single phase';
            
            % Set method variables
            app.modulation_method = 0;
            app.phase_encoding = 0;
            app.sample_choice = 0;
            app.Analog = 0;
            
            % Set channel properties
            app.numberofchannels = 1;
            app.Channel_Selected = 1;
            app.channel_distance = 150000;
            app.modulationfrequency = 3636363.6363636;
            
            % Set pixel properties
            app.xpixels = app.xpixelsEditField.Value;
            app.ypixels = app.ypixelsEditField.Value;
            app.pixelsize = 1;
            
            % Set signal and noise coefficients
            app.Csignal = 3.000*10^-6; % Default signal coefficient
            app.Cshot   = 1.364*10^-7; % Default shot-noise coefficient
            app.Celec   = 1.518*10^-7; % Default thermal noise coefficient
            
            % Set filter properies
            app.TC = 100*10^-6;
            app.PDT = 100*10^-6;
            app.filter_order = 1;
            
            % Set laser powers
            app.pump_power = 0;
            app.stokes_power = 0;
            
            % Set beam parameters
            app.w01x=0.44574;
            app.w01y=0.42165;
            app.w02x=0.51499;
            app.w02y=0.51305;
            app.zR1x=1.18;
            app.zR2x=1.3175;
            app.zR1y=1.0074;
            app.zR2y=1.3536;
            
            % Set field values
            app.ChanneldistanceHzEditField.Value = app.channel_distance;
            app.TCusEditField.Value = 100;
            app.PDTusEditField.Value = 100;
            app.FilterorderSpinner.Value = app.filter_order;
            app.ProbepowermWEditField.Value = app.pump_power;
            app.CarrierpowermWEditField.Value = app.stokes_power;
            app.CsignalEditField.Value = app.Csignal;
            app.CthermalEditField.Value = app.Celec;
            app.CshotEditField.Value = app.Cshot;
            app.w01xEditField.Value = app.w01x;
            app.w01yEditField.Value = app.w01y;
            app.w02xEditField.Value = app.w02x;
            app.w02yEditField.Value = app.w02y;
            app.wSRSyEditField.Value=app.w01y*app.w02y/sqrt(app.w01y^2+app.w02y^2);
            app.wSRSxEditField.Value=app.w01x*app.w02x/sqrt(app.w01x^2+app.w02x^2);
            
            % Set sample properties
            app.sample_id=1;
            app.sample_properties=[1;10;0;0];
            app.BeadsListBox.Items={'sample1'};
            app.BeadsListBox.ItemsData = 1;
            app.BeadsListBox.Value = 1;
            app.selected_sample=1;
            
            % Advanced settings: parameters
            app.Advanced_settings=0;
            set( findall(app.AdvancedsettingsPanel, '-property', 'Enable'), 'Enable', 'off')%https://nl.mathworks.com/matlabcentral/answers/72650-hide-and-disable-uicontrols-in-groups#comment_144156
            app.FrequencyMatrix=ones(1,1,1,6);
            app.Fast_Frequency=10^6;
            app.PowerMatrix=zeros(1,1,1,6);
            app.PhaseMatrix=zeros(1,1,1,6);
            
            % Advanced settings: Csignal matrix and table in the interface
            app.CsignalMatrix = app.Csignal*eye(6,6);
            app.UITable.ColumnFormat(:) = {'char'};
            celltest=cell(6,6);
            for j=1:6
                for i=1:6
                    celltest(j,i) = {num2str(app.CsignalMatrix(j,i),'%1.2e')};
                end
            end
            app.UITable.Data=celltest;

            % Update preview image
            Update_Image(app)
            
            % Show schematic setup image
            [Imag,map]=imread("Stokes modulation schematic.png");
            imshow(Imag,map,'Parent',app.UIAxes2)

        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            
            % Check validity input values
            if app.Advanced_settings
                if length(unique(app.FrequencyMatrix(1:app.numberofchannels).*(2*(app.phase_encoding*app.PhaseMatrix(1:app.numberofchannels)==0))-1))~=app.numberofchannels
                    app.ErrormessageLabel.Visible='on';
                    app.ErrormessageLabel.Text=["Error: 2 or more channels are encoded with","the same frequency and phase."];
                    return
                end
                if app.modulation_method && sum(not(app.Fast_Frequency>app.FrequencyMatrix(1:app.numberofchannels)))
                    app.ErrormessageLabel.Visible='on';
                    app.ErrormessageLabel.Text=["Error: Fast modulation frequency is slower","than the slow modulation frequencies."];
                    return
                end
            end
            
            % Make error message invisible.
            app.ErrormessageLabel.Visible='off';
            
            % Start simulation and show 'busy' message
            app.CalculatingLabel.Visible = 'on';
            pause(0.000000001)
            
            % Obtain sample properties
            sample_prop_input = input_sample_func(app);
            
            % Set signal coefficient matrix
            if app.Advanced_settings
                % Advanced settings
                Csig=app.CsignalMatrix(1:app.numberofchannels,1:app.numberofchannels);
            else
                % Simple settings
                Csig=app.Csignal*eye(app.numberofchannels);
            end
            
            % Run simulation
            SimulationLIA(app.sample_choice,app.modulation_method,app.phase_encoding,app.numberofchannels,app.modulationfrequency,app.channel_distance,app.pixelsize,app.xpixels,app.ypixels,app.pump_power,app.stokes_power,app.TC,app.PDT,app.filter_order,app.w01x,app.w01y,app.w02x,app.w02y,app.zR1x,app.zR1y,app.zR2x,app.zR2y,sample_prop_input,app.Celec,app.Cshot,Csig,app.Analog,app.CalculatingLabel,app.Advanced_settings,app.PowerMatrix(1:app.numberofchannels),app.FrequencyMatrix(1:app.numberofchannels),app.Fast_Frequency,app.phase_encoding*app.PhaseMatrix(1:app.numberofchannels));
        
            % Turn of 'busy' message
            app.CalculatingLabel.Visible = 'off';
            
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            app.sample_properties(2,app.selected_sample) = app.RadiusEditField.Value;
            Update_Image(app);
        end

        % Value changed function: xpixelsEditField
        function xpixelsEditFieldValueChanged(app, event)
            
            % Obtain new X pixel number and X image size.
            app.xpixels = app.xpixelsEditField.Value;
            app.XsizeumEditField.Value = app.xpixels * app.pixelsize;
            
            % Update preview image
            Update_Image(app);
        end

        % Value changed function: ypixelsEditField
        function ypixelsEditFieldValueChanged(app, event)
            
            % Obtain new Y pixel number and Y image size.
            app.ypixels = app.ypixelsEditField.Value;
            app.YsizeumEditField.Value = app.ypixels * app.pixelsize;
            
            % Update preview image
            Update_Image(app);
        end

        % Value changed function: PixelsizeumEditField
        function PixelsizeumEditFieldValueChanged(app, event)
            app.pixelsize = app.PixelsizeumEditField.Value;
            
            % Obtain new X and Y image size.
            app.XsizeumEditField.Value = app.xpixels * app.pixelsize;
            app.YsizeumEditField.Value = app.ypixels * app.pixelsize;
            
            % Update preview image
            Update_Image(app);
        end

        % Value changed function: CsignalEditField
        function CsignalEditFieldValueChanged(app, event)
            app.Csignal = app.CsignalEditField.Value;
            
        end

        % Value changed function: ChanneldistanceHzEditField
        function ChanneldistanceHzEditFieldValueChanged(app, event)
            app.channel_distance = app.ChanneldistanceHzEditField.Value;
            
        end

        % Value changed function: CthermalEditField
        function CthermalEditFieldValueChanged(app, event)
            app.Celec = app.CthermalEditField.Value;
            
        end

        % Value changed function: CshotEditField
        function CshotEditFieldValueChanged(app, event)
            app.Cshot = app.CshotEditField.Value;
            
        end

        % Value changed function: w01xEditField
        function w01xEditFieldValueChanged(app, event)
            app.w01x = app.w01xEditField.Value;
            app.wSRSxEditField.Value=app.w01x*app.w02x/sqrt(app.w01x^2+app.w02x^2);
            
        end

        % Value changed function: w01yEditField
        function w01yEditFieldValueChanged(app, event)
            app.w01y = app.w01yEditField.Value;
            app.wSRSyEditField.Value=app.w01y*app.w02y/sqrt(app.w01y^2+app.w02y^2);
            
        end

        % Value changed function: w02xEditField
        function w02xEditFieldValueChanged(app, event)
            app.w02x = app.w02xEditField.Value;
            app.wSRSxEditField.Value=app.w01x*app.w02x/sqrt(app.w01x^2+app.w02x^2);
            
        end

        % Value changed function: w02yEditField
        function w02yEditFieldValueChanged(app, event)
            app.w02y = app.w02yEditField.Value;
            app.wSRSyEditField.Value=app.w01y*app.w02y/sqrt(app.w01y^2+app.w02y^2);
            
        end

        % Value changed function: ProbepowermWEditField
        function ProbepowermWEditFieldValueChanged(app, event)
            app.pump_power = app.ProbepowermWEditField.Value;
            
        end

        % Value changed function: CarrierpowermWEditField
        function CarrierpowermWEditFieldValueChanged(app, event)
            app.stokes_power = app.CarrierpowermWEditField.Value;
            
        end

        % Value changed function: PDTusEditField
        function PDTusEditFieldValueChanged(app, event)
            app.PDT = app.PDTusEditField.Value * 10^-6;
            
        end

        % Value changed function: FilterorderSpinner
        function FilterorderSpinnerValueChanged(app, event)
            app.filter_order = app.FilterorderSpinner.Value;
            
        end

        % Value changed function: TCusEditField
        function TCusEditFieldValueChanged(app, event)
            app.TC = app.TCusEditField.Value * 10^-6;
            
        end

        % Value changed function: ModulationmethodDropDown
        function ModulationmethodDropDownValueChanged(app, event)
            value = app.ModulationmethodDropDown.Value;
            
            % Determine selected modulation method
            switch value
                case 'Single Carrier modulation'
                    app.modulation_method = 0;
                    app.CarrierpowermWLabel.Text='Carrier power (mW)';
                    app.FastcarriermodulationHzEditField.Visible='off';
                    app.FastcarriermodulationHzEditFieldLabel.Visible = 'off';
                case 'Double Carrier modulation'
                    app.modulation_method = 1;
                    app.CarrierpowermWLabel.Text='Carrier power (mW)';
                    app.FastcarriermodulationHzEditField.Visible='on';
                    app.FastcarriermodulationHzEditFieldLabel.Visible = 'on';
                case 'Carrier and Probe modulation'
                    app.modulation_method = 2;
                    app.CarrierpowermWLabel.Text='Probe power (mW)';
                    app.FastcarriermodulationHzEditField.Visible='on';
                    app.FastcarriermodulationHzEditFieldLabel.Visible = 'on';
            end
            
            % Show setup schematic for each modulation method
            if app.modulation_method==0
                [imag,map]=imread("Stokes modulation schematic.png");
            elseif app.modulation_method==1
                [imag,map]=imread("Double Stokes modulation schematic.png");%C:\Users\Tom\Documents\MATLAB\11-1-2019 MATLAB\Version 5\
            else
                [imag,map]=imread("Pump and Stokes modulation schematic.png");
            end
            imshow(imag,map,'Parent',app.UIAxes2)
            
            % If advanced settings is selected
            if app.Advanced_settings
                if app.modulation_method==0
                    % Carrier is split
                    app.CarrierpowermWEditField.Enable='off';
                    app.ProbepowermWEditField.Enable='on';
                    
                    % No fast modulation channel visible, single modulation frequency
                    app.ModfrequencyHzLabel.Text='Mod frequency (Hz)';
                    app.FastcarriermodulationHzEditField.Visible='off';
                    app.FastcarriermodulationHzEditFieldLabel.Visible = 'off';
                    
                elseif app.modulation_method==1
                    % Carrier is split
                    app.CarrierpowermWEditField.Enable='off';
                    app.ProbepowermWEditField.Enable='on';
                    
                    % Fast modulation channel visible, carrier channels
                    app.ModfrequencyHzLabel.Text='Slow carrier frequency (Hz)';
                    app.FastcarriermodulationHzEditField.Visible='on';
                    app.FastcarriermodulationHzEditFieldLabel.Visible = 'on';
                    
                else
                    % Probe is split
                    app.CarrierpowermWEditField.Enable='on';
                    app.ProbepowermWEditField.Enable='off';
                    
                    % Fast modulation channel visible, probe channels
                    app.ModfrequencyHzLabel.Text='Slow probe frequency (Hz)';
                    app.FastcarriermodulationHzEditField.Visible='on';
                    app.FastcarriermodulationHzEditFieldLabel.Visible = 'on';
                end
            end
            
        end

        % Value changed function: SamplechoiceDropDown
        function SamplechoiceDropDownValueChanged(app, event)
            value = app.SamplechoiceDropDown.Value;
            
            % Determine sample choice
            switch value
                case '2D bead'
                    app.sample_choice = 0;
                otherwise
                    app.sample_choice = 1;
            end
            
            % Update preview image
            Update_Image(app)
            
            % Edit labels for selected sample
            if app.sample_choice==0
                
               app.ObjectsizeLabel.Text = "Radius";
               app.YsizeumEditField.Enable = 'on';
               app.YpositionEditField.Enable = 'on';
               app.ypixelsEditField.Enable = 'on';
               app.SampleLabel.Visible='off';
               
            else
               
               app.ObjectsizeLabel.Text = "Object size";
               app.YsizeumEditField.Enable = 'off';
               app.YpositionEditField.Enable = 'off';
               app.ypixelsEditField.Enable = 'off';
               app.SampleLabel.Visible='on';
               
            end
            
        end

        % Value changed function: PhaseencodingDropDown
        function PhaseencodingDropDownValueChanged(app, event)
            value = app.PhaseencodingDropDown.Value;
            
            % Determine selected phase encoding
            switch value
                case 'Single phase'
                    app.phase_encoding = 0;
                otherwise
                    app.phase_encoding = 1;
            end
            
            % Enable or disable phase encoding fields
            Phase_encoding_Enable(app);
            
            
        end

        % Value changed function: ModulationfrequencyHzEditField
        function ModulationfrequencyHzEditFieldValueChanged(app, event)
            app.modulationfrequency = app.ModulationfrequencyHzEditField.Value;
            
        end

        % Value changed function: DatacollectionDropDown
        function DatacollectionDropDownValueChanged(app, event)
            value = app.DatacollectionDropDown.Value;
            
            % Analog or Digital data collection
            if strcmp(value,'Analog')
                app.Analog=1;
            else
                app.Analog=0;
            end
            
        end

        % Button pushed function: AddbeadButton
        function AddbeadButtonPushed(app, event)
            
            % Add new sample to the simulation
            app.sample_id=app.sample_id+1;
            app.BeadsListBox.Items{1,length(app.BeadsListBox.Items)+1} = ['sample',int2str(app.sample_id)];
            app.BeadsListBox.ItemsData=[app.BeadsListBox.ItemsData,length(app.BeadsListBox.ItemsData)+1];
            app.sample_properties=[app.sample_properties,[1;10;0;0]];
            
            % Update preview image
            Update_Image(app);
        end

        % Button pushed function: DeletebeadButton
        function DeletebeadButtonPushed(app, event)
            
            % If a valid sample is selected, delete its properties
            if app.selected_sample>0
                app.BeadsListBox.ItemsData(end)=[];
                app.BeadsListBox.Items(app.selected_sample)=[];
                app.sample_properties(:,app.selected_sample)=[];
            end
            
            % If no samples are left, set selected sample to 0
            if size(app.sample_properties,2)==0
                app.selected_sample=0;
            end
            
            % If selected sample is 0, set field values to 0 and update preview
            if app.selected_sample==0
                app.ChannelSpinner.Enable=0;
                app.RadiusEditField.Enable=0;
                app.XpositionEditField.Enable=0;
                app.YpositionEditField.Enable=0;
                Update_Image(app);
                return;
            end
            
            % If the last element is deleted, set the new selected sample to the first sample
            if app.selected_sample>size(app.sample_properties,2)
                app.selected_sample=1;
            end
            
            % Set field values to the next sample
            app.ChannelSpinner.Value=app.sample_properties(1,app.selected_sample);
            app.RadiusEditField.Value=app.sample_properties(2,app.selected_sample);
            app.XpositionEditField.Value=app.sample_properties(3,app.selected_sample);
            app.YpositionEditField.Value=app.sample_properties(4,app.selected_sample);
            
            % Update preview image
            Update_Image(app);
        end

        % Value changed function: BeadsListBox
        function BeadsListBoxValueChanged(app, event)
            
            % Change sample selection
            app.selected_sample = app.BeadsListBox.Value;
            
            % In case no selection to selection
            app.ChannelSpinner.Enable=1;
            app.RadiusEditField.Enable=1;
            app.XpositionEditField.Enable=1;
            if app.selected_sample==0
                app.YpositionEditField.Enable=1;
            end
            
            % Set field values to properties of newly selected sample
            app.ChannelSpinner.Value=app.sample_properties(1,app.selected_sample);
            app.RadiusEditField.Value=app.sample_properties(2,app.selected_sample);
            app.XpositionEditField.Value=app.sample_properties(3,app.selected_sample);
            app.YpositionEditField.Value=app.sample_properties(4,app.selected_sample);
            
        end

        % Value changed function: XpositionEditField
        function XpositionEditFieldValueChanged2(app, event)
            app.sample_properties(3,app.selected_sample) = app.XpositionEditField.Value;
            
            % Update preview image
            Update_Image(app);
        end

        % Value changed function: YpositionEditField
        function YpositionEditFieldValueChanged2(app, event)
            app.sample_properties(4,app.selected_sample) = app.YpositionEditField.Value;
            
            % Update preview image
            Update_Image(app);
        end

        % Value changed function: ChannelSpinner
        function ChannelSpinnerValueChanged(app, event)
            app.sample_properties(1,app.selected_sample) = app.ChannelSpinner.Value;
            
            % Update preview image
            Update_Image(app);
        end

        % Value changed function: NumberofchannelsSpinner
        function NumberofchannelsSpinnerValueChanged(app, event)
            app.numberofchannels = app.NumberofchannelsSpinner.Value;
            app.NumberofchannelsSpinner_2.Value = app.numberofchannels;
            
            % Update preview image
            Update_Image(app);
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            value=str2double(newData);
            
            % Check if new data is valid and add it to CsignalMatrix
            if isnan(value)
                app.CsignalMatrix(indices(1),indices(2))=0;
                app.UITable.Data(indices(1),indices(2))={'NaN'};
            else
                app.CsignalMatrix(indices(1),indices(2))=value;
                app.UITable.Data(indices(1),indices(2))={num2str(app.CsignalMatrix(indices(1),indices(2)),'%1.2e')};
            end
            
        end

        % Value changed function: AdvancedsettingsCheckBox
        function AdvancedsettingsCheckBoxValueChanged(app, event)
            app.Advanced_settings = app.AdvancedsettingsCheckBox.Value;
            if app.Advanced_settings
                % Advanced setting
                
                % Disable simple settings
                set(findall(app.AdvancedsettingsPanel,'-property','Enable'),'Enable','on')
                app.CsignalEditField.Enable='off';
                app.ChanneldistanceHzEditField.Enable='off';
                app.ModulationfrequencyHzEditField.Enable='off';
                
                % Disable simple power settings
                if app.modulation_method<2
                    % Stokes is split
                    app.CarrierpowermWEditField.Enable='off';
                else
                    % Pump is split
                    app.ProbepowermWEditField.Enable='off';
                end
            else
                % Simple setting
                
                % Enable simple settings
                set(findall(app.AdvancedsettingsPanel,'-property','Enable'),'Enable','off')
                app.CsignalEditField.Enable='on';
                app.ChanneldistanceHzEditField.Enable='on';
                app.ModulationfrequencyHzEditField.Enable='on';
                
                % Enable simple power settings
                if app.modulation_method<2
                    % Stokes is split
                    app.CarrierpowermWEditField.Enable='on';
                else
                    % Pump is split
                    app.ProbepowermWEditField.Enable='on';
                end
            end
            
            % Enable or disable phase encoding fields
            Phase_encoding_Enable(app)
            
        end

        % Value changed function: NumberofchannelsSpinner_2
        function NumberofchannelsSpinner_2ValueChanged(app, event)
            app.numberofchannels = app.NumberofchannelsSpinner_2.Value;
            app.NumberofchannelsSpinner.Value=app.numberofchannels;
            
            % Update preview image
            Update_Image(app);
        end

        % Value changed function: Channel1EditField
        function Channel1EditFieldValueChanged(app, event)
            app.PowerMatrix(1,1,1,1) = app.Channel1EditField.Value;
            
        end

        % Value changed function: Channel2EditField
        function Channel2EditFieldValueChanged(app, event)
            app.PowerMatrix(1,1,1,2) = app.Channel2EditField.Value;
            
        end

        % Value changed function: Channel3EditField
        function Channel3EditFieldValueChanged(app, event)
            app.PowerMatrix(1,1,1,3) = app.Channel3EditField.Value;
            
        end

        % Value changed function: Channel4EditField
        function Channel4EditFieldValueChanged(app, event)
            app.PowerMatrix(1,1,1,4) = app.Channel4EditField.Value;
            
        end

        % Value changed function: Channel5EditField
        function Channel5EditFieldValueChanged(app, event)
            app.PowerMatrix(1,1,1,5) = app.Channel5EditField.Value;
            
        end

        % Value changed function: Channel6EditField
        function Channel6EditFieldValueChanged(app, event)
            app.PowerMatrix(1,1,1,6) = app.Channel6EditField.Value;
            
        end

        % Value changed function: Freq1EditField
        function Freq1EditFieldValueChanged(app, event)
            app.FrequencyMatrix(1,1,1,1) = app.Freq1EditField.Value;
            
        end

        % Value changed function: Freq2EditField
        function Freq2EditFieldValueChanged(app, event)
            app.FrequencyMatrix(1,1,1,2) = app.Freq2EditField.Value;
            
        end

        % Value changed function: Freq3EditField
        function Freq3EditFieldValueChanged(app, event)
            app.FrequencyMatrix(1,1,1,3) = app.Freq3EditField.Value;
            
        end

        % Value changed function: Freq4EditField
        function Freq4EditFieldValueChanged(app, event)
            app.FrequencyMatrix(1,1,1,4) = app.Freq4EditField.Value;
            
        end

        % Value changed function: Freq5EditField
        function Freq5EditFieldValueChanged(app, event)
            app.FrequencyMatrix(1,1,1,5) = app.Freq5EditField.Value;
            
        end

        % Value changed function: Freq6EditField
        function Freq6EditFieldValueChanged(app, event)
            app.FrequencyMatrix(1,1,1,6) = app.Freq6EditField.Value;
            
        end

        % Value changed function: Phase1DropDown
        function Phase1DropDownValueChanged(app, event)
            value = app.Phase1DropDown.Value;
            
            switch value
                case '0'
                    app.PhaseMatrix(1,1,1,1)=0;
                otherwise
                    app.PhaseMatrix(1,1,1,1)=pi/2;
            end
                    
        end

        % Value changed function: Phase2DropDown
        function Phase2DropDownValueChanged(app, event)
            value = app.Phase2DropDown.Value;
            
            switch value
                case '0'
                    app.PhaseMatrix(1,1,1,2)=0;
                otherwise
                    app.PhaseMatrix(1,1,1,2)=pi/2;
            end
        end

        % Value changed function: Phase3DropDown
        function Phase3DropDownValueChanged(app, event)
            value = app.Phase3DropDown.Value;
            
            switch value
                case '0'
                    app.PhaseMatrix(1,1,1,3)=0;
                otherwise
                    app.PhaseMatrix(1,1,1,3)=pi/2;
            end
        end

        % Value changed function: Phase4DropDown
        function Phase4DropDownValueChanged(app, event)
            value = app.Phase4DropDown.Value;
            
            switch value
                case '0'
                    app.PhaseMatrix(1,1,1,4)=0;
                otherwise
                    app.PhaseMatrix(1,1,1,4)=pi/2;
            end
        end

        % Value changed function: Phase5DropDown
        function Phase5DropDownValueChanged(app, event)
            value = app.Phase5DropDown.Value;
            
            switch value
                case '0'
                    app.PhaseMatrix(1,1,1,5)=0;
                otherwise
                    app.PhaseMatrix(1,1,1,5)=pi/2;
            end
        end

        % Value changed function: Phase6DropDown
        function Phase6DropDownValueChanged(app, event)
            value = app.Phase6DropDown.Value;
            
            switch value
                case '0'
                    app.PhaseMatrix(1,1,1,6)=0;
                otherwise
                    app.PhaseMatrix(1,1,1,6)=pi/2;
            end
        end

        % Value changed function: FastcarriermodulationHzEditField
        function FastcarriermodulationHzEditFieldValueChanged(app, event)
            app.Fast_Frequency = app.FastcarriermodulationHzEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Tooltip = {'Start simulation. The simulation usually takes a minute to dozens of minutes (as long as the PDT and number of pixels is not too large).'};
            app.StartSimulationButton.Text = 'Start Simulation';
            app.UIAxes = uiaxes(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ModulationmethodDropDownLabel = uilabel(app.SetupTab);
            app.ModulationmethodDropDownLabel.Text = 'Modulation method';
            app.ModulationmethodDropDown = uidropdown(app.SetupTab);
            app.ModulationmethodDropDown.Items = {'Single Carrier modulation', 'Double Carrier modulation', 'Carrier and Probe modulation'};
            app.ModulationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationmethodDropDownValueChanged, true);
            app.ModulationmethodDropDown.Tooltip = {'Choice of the modulation method. The standard modulation method is modulating only 1 beam. The other options are double modulation methods (1 fast and 1 slow modulation). The channels are then the sidebands of their interaction.'};
            app.ModulationmethodDropDown.Value = 'Single Carrier modulation';
            app.SamplechoiceDropDownLabel = uilabel(app.SetupTab);
            app.SamplechoiceDropDownLabel.Text = 'Sample choice';
            app.SamplechoiceDropDown = uidropdown(app.SetupTab);
            app.SamplechoiceDropDown.Items = {'2D bead', '1D sample'};
            app.SamplechoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplechoiceDropDownValueChanged, true);
            app.SamplechoiceDropDown.Tooltip = {'Specify sample type. 2D bead is a cylinder scanned in the xy direction. 1D sample is a 1 dimensional sample scanned in the x direction.'};
            app.SamplechoiceDropDown.Value = '2D bead';
            app.PhaseencodingDropDownLabel = uilabel(app.SetupTab);
            app.PhaseencodingDropDownLabel.Text = 'Phase encoding';
            app.PhaseencodingDropDown = uidropdown(app.SetupTab);
            app.PhaseencodingDropDown.Items = {'Single phase', 'Double phase'};
            app.PhaseencodingDropDown.ValueChangedFcn = createCallbackFcn(app, @PhaseencodingDropDownValueChanged, true);
            app.PhaseencodingDropDown.Tooltip = {'Single phase encoding is the default method. Per frequency there is only 1 channel. For double phase encoding there are 2 channels for each modulation frequency. This is done by encoding the channels out-of-phase (a phase difference of pi/2).'};
            app.PhaseencodingDropDown.Value = 'Single phase';
            app.DatacollectionDropDownLabel = uilabel(app.SetupTab);
            app.DatacollectionDropDownLabel.Text = 'Data collection';
            app.DatacollectionDropDown = uidropdown(app.SetupTab);
            app.DatacollectionDropDown.Items = {'Digital', 'Analog'};
            app.DatacollectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DatacollectionDropDownValueChanged, true);
            app.DatacollectionDropDown.Tooltip = {'Digital data collection is for a setup in which only 1 sample is taken as a pixel. Analog data collection is for the case when the average is taken over multiple samples to obtain a pixel.'};
            app.DatacollectionDropDown.Value = 'Digital';
            app.UIAxes2 = uiaxes(app.SetupTab);
            title(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.InputparametersTab = uitab(app.TabGroup);
            app.InputparametersTab.Title = 'Input parameters';
            app.TCusEditFieldLabel = uilabel(app.InputparametersTab);
            app.TCusEditFieldLabel.Text = 'TC (us)';
            app.TCusEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.TCusEditField.LowerLimitInclusive = 'off';
            app.TCusEditField.Limits = [0 Inf];
            app.TCusEditField.ValueChangedFcn = createCallbackFcn(app, @TCusEditFieldValueChanged, true);
            app.TCusEditField.Tooltip = {'The time constant of the digital RC filter of the Lock-in Amplifier in microseconds'};
            app.TCusEditField.Value = 100;
            app.PDTusEditFieldLabel = uilabel(app.InputparametersTab);
            app.PDTusEditFieldLabel.Text = 'PDT (us)';
            app.PDTusEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.PDTusEditField.LowerLimitInclusive = 'off';
            app.PDTusEditField.Limits = [0 Inf];
            app.PDTusEditField.ValueChangedFcn = createCallbackFcn(app, @PDTusEditFieldValueChanged, true);
            app.PDTusEditField.Tooltip = {'The pixel dwell time in microseconds. Warning: if the PDT becomes too large'; ' it may significantly slow down the simulation.'};
            app.PDTusEditField.Value = 100;
            app.FilterorderSpinnerLabel = uilabel(app.InputparametersTab);
            app.FilterorderSpinnerLabel.Text = 'Filter order';
            app.FilterorderSpinner = uispinner(app.InputparametersTab);
            app.FilterorderSpinner.Limits = [1 8];
            app.FilterorderSpinner.RoundFractionalValues = 'on';
            app.FilterorderSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterorderSpinnerValueChanged, true);
            app.FilterorderSpinner.Tooltip = {'The filter order of the digital RC filter of the Lock-in Amplifier. This filter order is equal to the number of cascaded low pass filters. Its maximal value is 8.'};
            app.FilterorderSpinner.Value = 1;
            app.xpixelsEditFieldLabel = uilabel(app.InputparametersTab);
            app.xpixelsEditFieldLabel.Text = 'x pixels';
            app.xpixelsEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.xpixelsEditField.Limits = [1 Inf];
            app.xpixelsEditField.RoundFractionalValues = 'on';
            app.xpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @xpixelsEditFieldValueChanged, true);
            app.xpixelsEditField.Tooltip = {'Number of pixels in the x direction. Warning: if the number of pixels becomes too large'; ' it may slow down the simulation.'};
            app.xpixelsEditField.Value = 100;
            app.ypixelsLabel = uilabel(app.InputparametersTab);
            app.ypixelsLabel.Text = 'y pixels';
            app.ypixelsEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.ypixelsEditField.Limits = [1 Inf];
            app.ypixelsEditField.RoundFractionalValues = 'on';
            app.ypixelsEditField.ValueChangedFcn = createCallbackFcn(app, @ypixelsEditFieldValueChanged, true);
            app.ypixelsEditField.Tooltip = {'Number of pixels in the y direction. Warning: if the number of pixels becomes too large'; ' it may slow down the simulation.'};
            app.ypixelsEditField.Value = 100;
            app.PixelsizeumEditFieldLabel = uilabel(app.InputparametersTab);
            app.PixelsizeumEditFieldLabel.Text = 'Pixel size (um)';
            app.PixelsizeumEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.PixelsizeumEditField.LowerLimitInclusive = 'off';
            app.PixelsizeumEditField.Limits = [0 Inf];
            app.PixelsizeumEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizeumEditFieldValueChanged, true);
            app.PixelsizeumEditField.Tooltip = {'The pixel size in micrometer.'};
            app.PixelsizeumEditField.Value = 1;
            app.ProbepowermWEditFieldLabel = uilabel(app.InputparametersTab);
            app.ProbepowermWEditFieldLabel.Text = 'Probe power (mW)';
            app.ProbepowermWEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.ProbepowermWEditField.Limits = [0 Inf];
            app.ProbepowermWEditField.ValueChangedFcn = createCallbackFcn(app, @ProbepowermWEditFieldValueChanged, true);
            app.ProbepowermWEditField.Tooltip = {'Average power of the unmodulated beam (or in case of the Carrier and Probe modulation setup'; ' the slowly modulated beam).'};
            app.CarrierpowermWEditFieldLabel = uilabel(app.InputparametersTab);
            app.CarrierpowermWEditFieldLabel.Text = 'Carrier power (mW)';
            app.CarrierpowermWEditField = uieditfield(app.InputparametersTab, 'numeric');
            app.CarrierpowermWEditField.Limits = [0 Inf];
            app.CarrierpowermWEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierpowermWEditFieldValueChanged, true);
            app.CarrierpowermWEditField.Tooltip = {'Average power of the modulated beam in milliwatt'};
            app.SetupparametersTab = uitab(app.TabGroup);
            app.SetupparametersTab.Title = 'Setup parameters';
            app.CsignalEditFieldLabel = uilabel(app.SetupparametersTab);
            app.CsignalEditFieldLabel.Text = 'Csignal';
            app.CsignalEditField = uieditfield(app.SetupparametersTab, 'numeric');
            app.CsignalEditField.Limits = [0 Inf];
            app.CsignalEditField.ValueChangedFcn = createCallbackFcn(app, @CsignalEditFieldValueChanged, true);
            app.CsignalEditField.Tooltip = {'The SRS signal coefficient'};
            app.CthermalEditFieldLabel = uilabel(app.SetupparametersTab);
            app.CthermalEditFieldLabel.Tooltip = {'The thermal noise coefficient'};
            app.CthermalEditFieldLabel.Text = 'Cthermal';
            app.CthermalEditField = uieditfield(app.SetupparametersTab, 'numeric');
            app.CthermalEditField.Limits = [0 Inf];
            app.CthermalEditField.ValueChangedFcn = createCallbackFcn(app, @CthermalEditFieldValueChanged, true);
            app.CthermalEditField.Tooltip = {'Thermal noise coefficient'};
            app.CshotEditFieldLabel = uilabel(app.SetupparametersTab);
            app.CshotEditFieldLabel.Text = 'Cshot';
            app.CshotEditField = uieditfield(app.SetupparametersTab, 'numeric');
            app.CshotEditField.Limits = [0 Inf];
            app.CshotEditField.ValueChangedFcn = createCallbackFcn(app, @CshotEditFieldValueChanged, true);
            app.CshotEditField.Tooltip = {'The shot noise coefficient'};
            app.Beam1Panel = uipanel(app.SetupparametersTab);
            app.Beam1Panel.Title = 'Beam 1';
            app.w01xEditFieldLabel = uilabel(app.Beam1Panel);
            app.w01xEditFieldLabel.Text = 'w01x';
            app.w01xEditField = uieditfield(app.Beam1Panel, 'numeric');
            app.w01xEditField.LowerLimitInclusive = 'off';
            app.w01xEditField.Limits = [0 Inf];
            app.w01xEditField.ValueChangedFcn = createCallbackFcn(app, @w01xEditFieldValueChanged, true);
            app.w01xEditField.Tooltip = {'1/e2 beam radius (in um) of the first beam in the x direction.'};
            app.w01xEditField.Value = 1;
            app.w01yEditFieldLabel = uilabel(app.Beam1Panel);
            app.w01yEditFieldLabel.Text = 'w01y';
            app.w01yEditField = uieditfield(app.Beam1Panel, 'numeric');
            app.w01yEditField.LowerLimitInclusive = 'off';
            app.w01yEditField.Limits = [0 Inf];
            app.w01yEditField.ValueChangedFcn = createCallbackFcn(app, @w01yEditFieldValueChanged, true);
            app.w01yEditField.Tooltip = {'1/e2 beam radius (in um) of the first beam in the y direction.'};
            app.w01yEditField.Value = 1;
            app.Beam2Panel = uipanel(app.SetupparametersTab);
            app.Beam2Panel.Title = 'Beam 2';
            app.w02xEditFieldLabel = uilabel(app.Beam2Panel);
            app.w02xEditFieldLabel.Text = 'w02x';
            app.w02xEditField = uieditfield(app.Beam2Panel, 'numeric');
            app.w02xEditField.LowerLimitInclusive = 'off';
            app.w02xEditField.Limits = [0 Inf];
            app.w02xEditField.ValueChangedFcn = createCallbackFcn(app, @w02xEditFieldValueChanged, true);
            app.w02xEditField.Tooltip = {'1/e2 beam radius (in um) of the second beam in the x direction.'};
            app.w02xEditField.Value = 1;
            app.w02yEditFieldLabel = uilabel(app.Beam2Panel);
            app.w02yEditFieldLabel.Text = 'w02y';
            app.w02yEditField = uieditfield(app.Beam2Panel, 'numeric');
            app.w02yEditField.LowerLimitInclusive = 'off';
            app.w02yEditField.Limits = [0 Inf];
            app.w02yEditField.ValueChangedFcn = createCallbackFcn(app, @w02yEditFieldValueChanged, true);
            app.w02yEditField.Tooltip = {'1/e2 beam radius (in um) of the second beam in the y direction.'};
            app.w02yEditField.Value = 1;
            app.TextTextArea = uitextarea(app.SetupparametersTab);
            app.TextTextArea.Editable = 'off';
            app.TextTextArea_2 = uitextarea(app.SetupparametersTab);
            app.TextTextArea_2.Editable = 'off';
            app.TextTextArea_3 = uitextarea(app.SetupparametersTab);
            app.TextTextArea_3.Editable = 'off';
            app.TextTextArea_4 = uitextarea(app.SetupparametersTab);
            app.TextTextArea_4.Editable = 'off';
            app.TextTextArea_5 = uitextarea(app.SetupparametersTab);
            app.TextTextArea_5.Editable = 'off';
            app.EquationLabel = uilabel(app.SetupparametersTab);
            app.EquationLabel.Text = 'Equation';
            app.EffectiveSRSshapePanel = uipanel(app.SetupparametersTab);
            app.EffectiveSRSshapePanel.Title = 'Effective SRS shape';
            app.wSRSxEditFieldLabel = uilabel(app.EffectiveSRSshapePanel);
            app.wSRSxEditFieldLabel.Text = 'wSRSx';
            app.wSRSxEditField = uieditfield(app.EffectiveSRSshapePanel, 'numeric');
            app.wSRSxEditField.Editable = 'off';
            app.wSRSyEditFieldLabel = uilabel(app.EffectiveSRSshapePanel);
            app.wSRSyEditFieldLabel.Text = 'wSRSy';
            app.wSRSyEditField = uieditfield(app.EffectiveSRSshapePanel, 'numeric');
            app.wSRSyEditField.Editable = 'off';
            app.SRSisthemultiplicationofthetwobeamsLabel = uilabel(app.SetupparametersTab);
            app.SRSisthemultiplicationofthetwobeamsLabel.Text = {'SRS is the multiplication of the '; 'two beams'};
            app.ThebeamsareassumedtobefocussedGaussianbeamswithLabel = uilabel(app.SetupparametersTab);
            app.ThebeamsareassumedtobefocussedGaussianbeamswithLabel.Text = {'The beams are assumed to be focussed Gaussian beams'; 'with w as the 1/e2 radius.'};
            app.TextArea_3 = uitextarea(app.SetupparametersTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Signal and Noise coefficients'};
            app.TextArea_4 = uitextarea(app.SetupparametersTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Beam properties'};
            app.ChannelsTab = uitab(app.TabGroup);
            app.ChannelsTab.Title = 'Channels';
            app.RadiusEditFieldLabel = uilabel(app.ChannelsTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.ChannelsTab, 'numeric');
            app.RadiusEditField.Limits = [1 Inf];
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.RadiusEditField.Tooltip = {'Radius (2D) or object size (1D) of the sample in micrometers..'};
            app.RadiusEditField.Value = 10;
            app.ChanneldistanceHzEditFieldLabel = uilabel(app.ChannelsTab);
            app.ChanneldistanceHzEditFieldLabel.Text = 'Channel distance (Hz)';
            app.ChanneldistanceHzEditField = uieditfield(app.ChannelsTab, 'numeric');
            app.ChanneldistanceHzEditField.LowerLimitInclusive = 'off';
            app.ChanneldistanceHzEditField.Limits = [0 Inf];
            app.ChanneldistanceHzEditField.ValueChangedFcn = createCallbackFcn(app, @ChanneldistanceHzEditFieldValueChanged, true);
            app.ChanneldistanceHzEditField.Tooltip = {'The difference in modulation frequency between the channels.'};
            app.ChanneldistanceHzEditField.Value = 1;
            app.XsizeumEditFieldLabel = uilabel(app.ChannelsTab);
            app.XsizeumEditFieldLabel.Text = 'X size (um)';
            app.XsizeumEditField = uieditfield(app.ChannelsTab, 'numeric');
            app.XsizeumEditField.Editable = 'off';
            app.XsizeumEditField.Tooltip = {'The Field Of View in the x direction in micrometer.'};
            app.XsizeumEditField.Value = 256;
            app.YsizeumEditFieldLabel = uilabel(app.ChannelsTab);
            app.YsizeumEditFieldLabel.Text = 'Y size (um)';
            app.YsizeumEditField = uieditfield(app.ChannelsTab, 'numeric');
            app.YsizeumEditField.Editable = 'off';
            app.YsizeumEditField.Tooltip = {'The Field Of View in the x direction in micrometer.'};
            app.YsizeumEditField.Value = 256;
            app.ObjectsizeLabel = uilabel(app.ChannelsTab);
            app.ObjectsizeLabel.Text = 'Object size';
            app.ModulationfrequencyHzEditFieldLabel = uilabel(app.ChannelsTab);
            app.ModulationfrequencyHzEditFieldLabel.Text = 'Modulation frequency (Hz)';
            app.ModulationfrequencyHzEditField = uieditfield(app.ChannelsTab, 'numeric');
            app.ModulationfrequencyHzEditField.LowerLimitInclusive = 'off';
            app.ModulationfrequencyHzEditField.Limits = [0 Inf];
            app.ModulationfrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @ModulationfrequencyHzEditFieldValueChanged, true);
            app.ModulationfrequencyHzEditField.Tooltip = {'The modulation frequency of the first channel.'};
            app.ModulationfrequencyHzEditField.Value = 3636363.6363636;
            app.BeadsListBoxLabel = uilabel(app.ChannelsTab);
            app.BeadsListBoxLabel.Text = 'Beads';
            app.BeadsListBox = uilistbox(app.ChannelsTab);
            app.BeadsListBox.Items = {'Item 1'};
            app.BeadsListBox.ValueChangedFcn = createCallbackFcn(app, @BeadsListBoxValueChanged, true);
            app.BeadsListBox.Tooltip = {'List of all samples.'};
            app.AddbeadButton = uibutton(app.ChannelsTab, 'push');
            app.AddbeadButton.ButtonPushedFcn = createCallbackFcn(app, @AddbeadButtonPushed, true);
            app.AddbeadButton.Text = 'Add bead';
            app.DeletebeadButton = uibutton(app.ChannelsTab, 'push');
            app.DeletebeadButton.ButtonPushedFcn = createCallbackFcn(app, @DeletebeadButtonPushed, true);
            app.DeletebeadButton.Text = 'Delete bead';
            app.ChannelSpinnerLabel = uilabel(app.ChannelsTab);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.ChannelsTab);
            app.ChannelSpinner.Limits = [1 6];
            app.ChannelSpinner.RoundFractionalValues = 'on';
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelSpinnerValueChanged, true);
            app.ChannelSpinner.Tooltip = {'Channel of the sample.'};
            app.ChannelSpinner.Value = 1;
            app.XpositionEditFieldLabel = uilabel(app.ChannelsTab);
            app.XpositionEditFieldLabel.Text = 'X position';
            app.XpositionEditField = uieditfield(app.ChannelsTab, 'numeric');
            app.XpositionEditField.ValueChangedFcn = createCallbackFcn(app, @XpositionEditFieldValueChanged2, true);
            app.XpositionEditField.Tooltip = {'x position of the sample in micrometers.'};
            app.YpositionEditFieldLabel = uilabel(app.ChannelsTab);
            app.YpositionEditFieldLabel.Text = 'Y position';
            app.YpositionEditField = uieditfield(app.ChannelsTab, 'numeric');
            app.YpositionEditField.ValueChangedFcn = createCallbackFcn(app, @YpositionEditFieldValueChanged2, true);
            app.YpositionEditField.Tooltip = {'y position of the sample in micrometers.'};
            app.NumberofchannelsSpinnerLabel = uilabel(app.ChannelsTab);
            app.NumberofchannelsSpinnerLabel.Text = 'Number of channels';
            app.NumberofchannelsSpinner = uispinner(app.ChannelsTab);
            app.NumberofchannelsSpinner.Limits = [1 6];
            app.NumberofchannelsSpinner.RoundFractionalValues = 'on';
            app.NumberofchannelsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsSpinnerValueChanged, true);
            app.NumberofchannelsSpinner.Tooltip = {'Number of channels. Maximal number of channels is 6.'};
            app.NumberofchannelsSpinner.Value = 1;
            app.TextArea = uitextarea(app.ChannelsTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Global properties'};
            app.TextArea_2 = uitextarea(app.ChannelsTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Sample properties'};
            app.SampleLabel = uilabel(app.ChannelsTab);
            app.SampleLabel.Visible = 'off';
            app.SampleLabel.Text = 'Sample';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.AdvancedsettingsCheckBox = uicheckbox(app.AdvancedTab);
            app.AdvancedsettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedsettingsCheckBoxValueChanged, true);
            app.AdvancedsettingsCheckBox.Tooltip = {'Activate Advanced settings or keep the simple settings from the previous tabs.'};
            app.AdvancedsettingsCheckBox.Text = 'Advanced settings';
            app.AdvancedsettingsPanel = uipanel(app.AdvancedTab);
            app.AdvancedsettingsPanel.Title = 'Advanced settings';
            app.CarrierpowermWLabel = uilabel(app.AdvancedsettingsPanel);
            app.CarrierpowermWLabel.Text = 'Carrier power (mW)';
            app.Channel1EditFieldLabel = uilabel(app.AdvancedsettingsPanel);
            app.Channel1EditFieldLabel.Text = 'Channel 1';
            app.Channel1EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Channel1EditField.Limits = [0 Inf];
            app.Channel1EditField.ValueChangedFcn = createCallbackFcn(app, @Channel1EditFieldValueChanged, true);
            app.Channel1EditField.Tooltip = {'Power of channel 1. Replaces the channel power from the ''Input parameters'' tab'};
            app.Channel2EditFieldLabel = uilabel(app.AdvancedsettingsPanel);
            app.Channel2EditFieldLabel.Text = 'Channel 2';
            app.Channel2EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Channel2EditField.Limits = [0 Inf];
            app.Channel2EditField.ValueChangedFcn = createCallbackFcn(app, @Channel2EditFieldValueChanged, true);
            app.Channel2EditField.Tooltip = {'Power of channel 2. Replaces the channel power from the ''Input parameters'' tab'};
            app.Channel3EditFieldLabel = uilabel(app.AdvancedsettingsPanel);
            app.Channel3EditFieldLabel.Text = 'Channel 3';
            app.Channel3EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Channel3EditField.Limits = [0 Inf];
            app.Channel3EditField.ValueChangedFcn = createCallbackFcn(app, @Channel3EditFieldValueChanged, true);
            app.Channel3EditField.Tooltip = {'Power of channel 3. Replaces the channel power from the ''Input parameters'' tab'};
            app.Channel4EditFieldLabel = uilabel(app.AdvancedsettingsPanel);
            app.Channel4EditFieldLabel.Text = 'Channel 4';
            app.Channel4EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Channel4EditField.Limits = [0 Inf];
            app.Channel4EditField.ValueChangedFcn = createCallbackFcn(app, @Channel4EditFieldValueChanged, true);
            app.Channel4EditField.Tooltip = {'Power of channel 4. Replaces the channel power from the ''Input parameters'' tab'};
            app.Channel5EditFieldLabel = uilabel(app.AdvancedsettingsPanel);
            app.Channel5EditFieldLabel.Text = 'Channel 5';
            app.Channel5EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Channel5EditField.Limits = [0 Inf];
            app.Channel5EditField.ValueChangedFcn = createCallbackFcn(app, @Channel5EditFieldValueChanged, true);
            app.Channel5EditField.Tooltip = {'Power of channel 5. Replaces the channel power from the ''Input parameters'' tab'};
            app.Channel6EditFieldLabel = uilabel(app.AdvancedsettingsPanel);
            app.Channel6EditFieldLabel.Text = 'Channel 6';
            app.Channel6EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Channel6EditField.Limits = [0 Inf];
            app.Channel6EditField.ValueChangedFcn = createCallbackFcn(app, @Channel6EditFieldValueChanged, true);
            app.Channel6EditField.Tooltip = {'Power of channel 6. Replaces the channel power from the ''Input parameters'' tab'};
            app.ModfrequencyHzLabel = uilabel(app.AdvancedsettingsPanel);
            app.ModfrequencyHzLabel.Text = 'Mod frequency (Hz)';
            app.Freq1EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Freq1EditField.LowerLimitInclusive = 'off';
            app.Freq1EditField.Limits = [0 Inf];
            app.Freq1EditField.ValueChangedFcn = createCallbackFcn(app, @Freq1EditFieldValueChanged, true);
            app.Freq1EditField.Tooltip = {'Modulation frequency of channel 1. Replaces modulation frequency and channel distance from the ''Channels'' tab.'};
            app.Freq1EditField.Value = 1;
            app.Freq2EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Freq2EditField.LowerLimitInclusive = 'off';
            app.Freq2EditField.Limits = [0 Inf];
            app.Freq2EditField.ValueChangedFcn = createCallbackFcn(app, @Freq2EditFieldValueChanged, true);
            app.Freq2EditField.Tooltip = {'Modulation frequency of channel 2. Replaces modulation frequency and channel distance from the ''Channels'' tab.'};
            app.Freq2EditField.Value = 1;
            app.Freq3EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Freq3EditField.LowerLimitInclusive = 'off';
            app.Freq3EditField.Limits = [0 Inf];
            app.Freq3EditField.ValueChangedFcn = createCallbackFcn(app, @Freq3EditFieldValueChanged, true);
            app.Freq3EditField.Tooltip = {'Modulation frequency of channel 3. Replaces modulation frequency and channel distance from the ''Channels'' tab.'};
            app.Freq3EditField.Value = 1;
            app.Freq4EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Freq4EditField.LowerLimitInclusive = 'off';
            app.Freq4EditField.Limits = [0 Inf];
            app.Freq4EditField.ValueChangedFcn = createCallbackFcn(app, @Freq4EditFieldValueChanged, true);
            app.Freq4EditField.Tooltip = {'Modulation frequency of channel 4. Replaces modulation frequency and channel distance from the ''Channels'' tab.'};
            app.Freq4EditField.Value = 1;
            app.Freq5EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Freq5EditField.LowerLimitInclusive = 'off';
            app.Freq5EditField.Limits = [0 Inf];
            app.Freq5EditField.ValueChangedFcn = createCallbackFcn(app, @Freq5EditFieldValueChanged, true);
            app.Freq5EditField.Tooltip = {'Modulation frequency of channel 5. Replaces modulation frequency and channel distance from the ''Channels'' tab.'};
            app.Freq5EditField.Value = 1;
            app.Freq6EditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.Freq6EditField.LowerLimitInclusive = 'off';
            app.Freq6EditField.Limits = [0 Inf];
            app.Freq6EditField.ValueChangedFcn = createCallbackFcn(app, @Freq6EditFieldValueChanged, true);
            app.Freq6EditField.Tooltip = {'Modulation frequency of channel 6. Replaces modulation frequency and channel distance from the ''Channels'' tab.'};
            app.Freq6EditField.Value = 1;
            app.NumberofchannelsSpinner_2Label = uilabel(app.AdvancedsettingsPanel);
            app.NumberofchannelsSpinner_2Label.Text = 'Number of channels';
            app.NumberofchannelsSpinner_2 = uispinner(app.AdvancedsettingsPanel);
            app.NumberofchannelsSpinner_2.Limits = [1 6];
            app.NumberofchannelsSpinner_2.RoundFractionalValues = 'on';
            app.NumberofchannelsSpinner_2.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsSpinner_2ValueChanged, true);
            app.NumberofchannelsSpinner_2.Tooltip = {'Number of channels. Maximal number of channels is 6.'};
            app.NumberofchannelsSpinner_2.Value = 1;
            app.UITable = uitable(app.AdvancedsettingsPanel);
            app.UITable.ColumnName = {'M1'; 'M2'; 'M3'; 'M4'; 'M5'; 'M6'};
            app.UITable.RowName = {'Ch1 '; 'Ch2 '; 'Ch3 '; 'Ch4 '; 'Ch5 '; 'Ch6 '};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Tooltip = {'Table containing the SRS Signal coefficients with the columns representing the material types and the rows the modulation channels. So Each column is effectively a small selection of the Raman spectrum of the material and each row corresponds to one wavenumber.  When advanced is active'; ' this table replaces the standard Csignal from the ''Setup parameters'' tab.'};
            app.MaterialLabel = uilabel(app.AdvancedsettingsPanel);
            app.MaterialLabel.Text = 'Material';
            app.ChannelLabel = uilabel(app.AdvancedsettingsPanel);
            app.ChannelLabel.Text = 'Channel';
            app.Phase1DropDown = uidropdown(app.AdvancedsettingsPanel);
            app.Phase1DropDown.Items = {'0', 'pi/2'};
            app.Phase1DropDown.ValueChangedFcn = createCallbackFcn(app, @Phase1DropDownValueChanged, true);
            app.Phase1DropDown.Tooltip = {'Phase of channel 1'; ' which can be 0 (in-phase) or pi/2 (quadrature). This option is only available when double phase encoding is enabled.'};
            app.Phase1DropDown.Value = '0';
            app.Phase2DropDown = uidropdown(app.AdvancedsettingsPanel);
            app.Phase2DropDown.Items = {'0', 'pi/2'};
            app.Phase2DropDown.ValueChangedFcn = createCallbackFcn(app, @Phase2DropDownValueChanged, true);
            app.Phase2DropDown.Tooltip = {'Phase of channel 2'; ' which can be 0 (in-phase) or pi/2 (quadrature). This option is only available when double phase encoding is enabled.'};
            app.Phase2DropDown.Value = '0';
            app.Phase3DropDown = uidropdown(app.AdvancedsettingsPanel);
            app.Phase3DropDown.Items = {'0', 'pi/2'};
            app.Phase3DropDown.ValueChangedFcn = createCallbackFcn(app, @Phase3DropDownValueChanged, true);
            app.Phase3DropDown.Tooltip = {'Phase of channel 3'; ' which can be 0 (in-phase) or pi/2 (quadrature). This option is only available when double phase encoding is enabled.'};
            app.Phase3DropDown.Value = '0';
            app.Phase4DropDown = uidropdown(app.AdvancedsettingsPanel);
            app.Phase4DropDown.Items = {'0', 'pi/2'};
            app.Phase4DropDown.ValueChangedFcn = createCallbackFcn(app, @Phase4DropDownValueChanged, true);
            app.Phase4DropDown.Tooltip = {'Phase of channel 4'; ' which can be 0 (in-phase) or pi/2 (quadrature). This option is only available when double phase encoding is enabled.'};
            app.Phase4DropDown.Value = '0';
            app.Phase5DropDown = uidropdown(app.AdvancedsettingsPanel);
            app.Phase5DropDown.Items = {'0', 'pi/2'};
            app.Phase5DropDown.ValueChangedFcn = createCallbackFcn(app, @Phase5DropDownValueChanged, true);
            app.Phase5DropDown.Tooltip = {'Phase of channel 5'; ' which can be 0 (in-phase) or pi/2 (quadrature). This option is only available when double phase encoding is enabled.'};
            app.Phase5DropDown.Value = '0';
            app.Phase6DropDown = uidropdown(app.AdvancedsettingsPanel);
            app.Phase6DropDown.Items = {'0', 'pi/2'};
            app.Phase6DropDown.ValueChangedFcn = createCallbackFcn(app, @Phase6DropDownValueChanged, true);
            app.Phase6DropDown.Tooltip = {'Phase of channel 6'; ' which can be 0 (in-phase) or pi/2 (quadrature). This option is only available when double phase encoding is enabled.'};
            app.Phase6DropDown.Value = '0';
            app.PhaseLabel = uilabel(app.AdvancedsettingsPanel);
            app.PhaseLabel.Text = 'Phase';
            app.FastcarriermodulationHzEditFieldLabel = uilabel(app.AdvancedsettingsPanel);
            app.FastcarriermodulationHzEditFieldLabel.Visible = 'off';
            app.FastcarriermodulationHzEditFieldLabel.Text = 'Fast carrier modulation (Hz)';
            app.FastcarriermodulationHzEditField = uieditfield(app.AdvancedsettingsPanel, 'numeric');
            app.FastcarriermodulationHzEditField.Limits = [0 Inf];
            app.FastcarriermodulationHzEditField.ValueChangedFcn = createCallbackFcn(app, @FastcarriermodulationHzEditFieldValueChanged, true);
            app.FastcarriermodulationHzEditField.Visible = 'off';
            app.FastcarriermodulationHzEditField.Tooltip = {'The fast modulation frequency of the carrier'};
            app.FastcarriermodulationHzEditField.Value = 1000000;
            app.CalculatingLabel = uilabel(app.UIFigure);
            app.CalculatingLabel.Text = 'Calculating...';
            app.ErrormessageLabel = uilabel(app.UIFigure);
            app.ErrormessageLabel.Visible = 'off';
            app.ErrormessageLabel.Text = 'Error message';
            app.PreviewLabel = uilabel(app.UIFigure);
            app.PreviewLabel.Text = 'Preview';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImagingSimulationInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end