classdef OptimalSettingsInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CalculateSettingsButton       matlab.ui.control.Button
        ImagingTimesEditFieldLabel    matlab.ui.control.Label
        outputImagingTimes            matlab.ui.control.NumericEditField
        CarrierPoweronLabel           matlab.ui.control.Label
        outputStokesPoweronSample     matlab.ui.control.NumericEditField
        ProbePoweronLabel             matlab.ui.control.Label
        outputPumppoweronSample       matlab.ui.control.NumericEditField
        FilterorderEditFieldLabel     matlab.ui.control.Label
        outputOrder                   matlab.ui.control.NumericEditField
        TimeconstantsLabel            matlab.ui.control.Label
        outputTC                      matlab.ui.control.NumericEditField
        PixeldwelltimesLabel          matlab.ui.control.Label
        outputPixeldwelltime          matlab.ui.control.NumericEditField
        CalculatingLabel              matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        Parameters1Tab                matlab.ui.container.Tab
        OptimizeButtonGroup           matlab.ui.container.ButtonGroup
        ImagingTimeButton             matlab.ui.control.RadioButton
        CNRButton                     matlab.ui.control.RadioButton
        ButtonGroup                   matlab.ui.container.ButtonGroup
        DensetightlypackedparticlesButton  matlab.ui.control.RadioButton
        SingleparticleButton          matlab.ui.control.RadioButton
        CNREditField_2Label           matlab.ui.control.Label
        CNREditField_2                matlab.ui.control.NumericEditField
        TimesLabel                    matlab.ui.control.Label
        ImagingTimeEditField          matlab.ui.control.NumericEditField
        PixelDwellEditFieldLabel      matlab.ui.control.Label
        PixelDwellEditField           matlab.ui.control.NumericEditField
        ImagingLabel                  matlab.ui.control.Label
        TimesLabel_2                  matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        Panel                         matlab.ui.container.Panel
        IncludeflybackCheckBox        matlab.ui.control.CheckBox
        FlybackEditFieldLabel         matlab.ui.control.Label
        FlybackEditField              matlab.ui.control.NumericEditField
        xpixelsEditFieldLabel         matlab.ui.control.Label
        xpixelsEditField              matlab.ui.control.NumericEditField
        ypixelsEditFieldLabel         matlab.ui.control.Label
        ypixelsEditField              matlab.ui.control.NumericEditField
        xFOVumEditFieldLabel          matlab.ui.control.Label
        xFOVumEditField               matlab.ui.control.NumericEditField
        yFOVumEditFieldLabel          matlab.ui.control.Label
        yFOVumEditField               matlab.ui.control.NumericEditField
        ObjectsizeumEditFieldLabel    matlab.ui.control.Label
        ObjectsizeumEditField         matlab.ui.control.NumericEditField
        PixelsizeumEditFieldLabel     matlab.ui.control.Label
        PixelsizeumEditField          matlab.ui.control.NumericEditField
        Parameters2Tab                matlab.ui.container.Tab
        Filterorder2EditField         matlab.ui.control.NumericEditField
        toLabel                       matlab.ui.control.Label
        FindoptimalorderCheckBox      matlab.ui.control.CheckBox
        FilterorderEditField_2Label   matlab.ui.control.Label
        FilterorderEditField_2        matlab.ui.control.NumericEditField
        Panel_2                       matlab.ui.container.Panel
        CarrierpowermWEditFieldLabel  matlab.ui.control.Label
        CarrierpowermWEditField       matlab.ui.control.NumericEditField
        ProbepowermWEditFieldLabel    matlab.ui.control.Label
        ProbepowermWEditField         matlab.ui.control.NumericEditField
        LaserpoweronsamplePanel       matlab.ui.container.Panel
        TotalpowermWEditFieldLabel    matlab.ui.control.Label
        TotalpowermWEditField         matlab.ui.control.NumericEditField
        PowerSwitchLabel              matlab.ui.control.Label
        PowerSwitch                   matlab.ui.control.Switch
        SetupparametersTab            matlab.ui.container.Tab
        Beam1Panel                    matlab.ui.container.Panel
        w01EditFieldLabel             matlab.ui.control.Label
        w01EditField                  matlab.ui.control.NumericEditField
        Beam2Panel                    matlab.ui.container.Panel
        w02EditFieldLabel             matlab.ui.control.Label
        w02EditField                  matlab.ui.control.NumericEditField
        CsignalEditFieldLabel         matlab.ui.control.Label
        CsignalEditField              matlab.ui.control.NumericEditField
        CthermalEditFieldLabel        matlab.ui.control.Label
        CthermalEditField             matlab.ui.control.NumericEditField
        CshotEditFieldLabel           matlab.ui.control.Label
        CshotEditField                matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        SamplemWLabel                 matlab.ui.control.Label
        SamplemWLabel_2               matlab.ui.control.Label
        OutputparametersLabel         matlab.ui.control.Label
        CNREditFieldLabel             matlab.ui.control.Label
        CNREditField                  matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        
        % Define power parameters
        PowerOnSample % Total power on sample 
        Carrier % Carrier power on sample
        Probe % Probe power on sample
        PowerSwitchVal % Power switch: Total Power or Carrier and Probe Power
        
        % Define filter order parameters
        filterorder % smallest filter order
        filterordermax % largest filter order
        FilterOrderCheck % Filter order switch: Find optimal order 'On' or 'Off'
        
        % Define pixels parameters
        xpixels % number of x pixels
        ypixels % number of y pixels
        pixelsize % Pixel size
        objectsize % Object size in optimization simulation (aimed resolution)
        
        % Define Time and CNR parameters
        CNR % Contrast to noise ration
        PDT % Pixel dwell time
        ImagingTime % Total Imaging Time
        TimeSwitch % Measurement Time or Pixel Dwell Time
        OptimizeSwitch % Time (Imaging or PDT) or CNR
        choice % Dense or Single Particle
        flybackcheck % Include Flyback: 'On' or 'Off'
        flyback % Flyback time in % of PDT
        
        % Beam parameters (beam waist and Rayleigh range)
        Csignal % Signal coefficient
        Cshot % Shot noise coefficient
        Celec % Electronic noise coefficient
        w01x % beam waist 1 x direction
        w01y
        w02x
        w02y
        zR1x % Rayleigh range 1 x direction
        zR1y
        zR2x
        zR2y
        
    end

    methods (Access = private)
    
        function [] = ImagingTimeUpdate(app)
            % Function to update the Imaging Time
            
            app.ImagingTime = app.PDT*app.xpixels*app.ypixels*(app.flybackcheck*(100+app.flyback)/100+(1-app.flybackcheck));
            app.ImagingTimeEditField.Value = app.ImagingTime;
            
            
        end
        
        function [] = PDTUpdate(app)
            % Function to update the Pixel Dwell Time
            
            app.PDT = app.ImagingTime/(app.xpixels*app.ypixels*(app.flybackcheck*(100+app.flyback)/100+(1-app.flybackcheck)));
            app.PixelDwellEditField.Value = app.PDT;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.PowerOnSample = 20; % in mW
            app.Carrier = 20*2/3; % in mW
            app.Probe = 20/3; % in mW
            app.PowerSwitchVal = 1;
            
            app.filterorder = 1;
            app.filterordermax = 1;
            app.FilterOrderCheck = 0;
            
            app.xpixels = 128;
            app.ypixels = 128;
            app.pixelsize = 1; % in um
            app.objectsize = 1; % in um
            
            app.CNR=3;
            app.PDT = 0.0006103515625; % in seconds
            app.ImagingTime = 10; % in seconds
            app.flybackcheck = 0;
            app.flyback = 50; % in percentage
            app.TimeSwitch = '1';
            app.OptimizeSwitch = 1;
            app.choice = 1;
            
            % Beam parameters (beam waist and Rayleigh range)
            app.Csignal = 3.72*10^-6; % V/mW^2 for our narrowband TA
            app.Cshot = 1.26*10^-7; % V/sqrt(mW Hz) for our narrowband TA
            app.Celec = 5.11*10^-8; % V/sqrt(Hz) for our narrowband TA
            app.w01x = 0.44574; % in um
            app.w01y = 0.42165;
            app.w02x = 0.51499;
            app.w02y = 0.51305;
            app.zR1x = 1.18; % in um
            app.zR2x = 1.3175;
            app.zR1y = 1.0074;
            app.zR2y = 1.3536;
            
            % Set Label texts
            app.Label_2.Text = ['(V/mW',char(178),')'];
            app.Label_3.Text = ['(V/',char(8730),'mW Hz)'];
            app.Label_4.Text = ['(V/',char(8730),'Hz)'];
            app.Label_5.Text = ['in ',char(181),'m'];
            app.Label_6.Text = ['in ',char(181),'m'];
            
            % Set Visibility
            app.Filterorder2EditField.Visible = 0;
            app.toLabel.Visible = 0;
            app.CalculatingLabel.Visible = 'Off';
            
        end

        % Button pushed function: CalculateSettingsButton
        function CalculateSettingsButtonPushed(app, event)
            
            % Show "Calculating..." message
            app.CalculatingLabel.Visible='On';
            pause(0.0000000001) % pause so that "Calculating..." can become visible
            
            % set CNR or PDT for optimization
            if app.OptimizeSwitch==1
                cnr=app.CNR;
                PDTlocal=[];
            else
                cnr=[];
                PDTlocal=app.PDT;
            end
            
            % Choose total power or individual powers
            if app.PowerSwitchVal
                CarrierPowerInput=app.PowerOnSample*2/3;
                ProbePowerInput=app.PowerOnSample*1/3;
            else
                CarrierPowerInput=app.Carrier;
                ProbePowerInput=app.Probe;
            end
            
            % Obtain optimal settings
            [snr,order,TC,PDTlocal,CNRcorr]=SystematicSettings(cnr,PDTlocal,app.pixelsize,app.objectsize,CarrierPowerInput,ProbePowerInput,app.Csignal,app.Cshot,app.Celec,app.choice,app.filterorder,app.filterordermax,app.w01x,app.w01y,app.w02x,app.w02y,app.zR1x,app.zR2x,app.zR1y,app.zR2y);
            
            % Update output fields
            %app.outputSNR.Value=snr;
            app.CNREditField.Value = CNRcorr;
            app.outputOrder.Value=order;
            app.outputStokesPoweronSample.Value=CarrierPowerInput;
            app.outputPumppoweronSample.Value=ProbePowerInput;
            app.outputTC.Value=TC;
            app.outputPixeldwelltime.Value=PDTlocal;
            app.outputImagingTimes.Value=PDTlocal*app.xpixels*app.ypixels*(app.flybackcheck*(100+app.flyback)/100+1);
            
            % Switch "Calculating..." off
            app.CalculatingLabel.Visible='Off';
            
        end

        % Value changed function: TotalpowermWEditField
        function TotalpowermWEditFieldValueChanged(app, event)
            app.PowerOnSample = app.TotalpowermWEditField.Value;
            
        end

        % Selection changed function: OptimizeButtonGroup
        function OptimizeButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptimizeButtonGroup.SelectedObject;
            
            % Optimize for CNR of Imaging Time
            switch selectedButton.Text
                case "Imaging Time"
                    app.OptimizeSwitch = 1; % Imaging Time
                otherwise
                    app.OptimizeSwitch = 0; % CNR
            end
            
            % Select CNR, PDT or Imaging Time as visible
            if app.OptimizeSwitch == 1
                app.CNREditField_2.Enable = 'on';
                app.ImagingTimeEditField.Enable = 'off';
                app.PixelDwellEditField.Enable = 'off';
                
            else
                app.CNREditField_2.Enable = 'off';
                
                if app.TimeSwitch == '1'
                    app.ImagingTimeEditField.Enable = 'on';
                else
                    app.PixelDwellEditField.Enable = 'on';
                end
                
            end
        end

        % Value changed function: FilterorderEditField_2
        function FilterorderEditField_2ValueChanged(app, event)
            app.filterorder = app.FilterorderEditField_2.Value;
            
            % Use only 1 filter order
            if app.FilterOrderCheck == 0
                app.filterordermax = app.filterorder;
                
            elseif app.filterordermax < app.filterorder
                app.Filterorder2EditField.Value = app.filterorder;
                app.filterordermax = app.filterorder;
                
            end
            
        end

        % Value changed function: Filterorder2EditField
        function Filterorder2EditFieldValueChanged(app, event)
            app.filterordermax = app.Filterorder2EditField.Value;
            
            % Set new maximal filter order for optimal filter order selection
            if app.filterordermax < app.filterorder
                app.filterordermax = app.filterorder;
                app.Filterorder2EditField.Value = app.filterordermax;
            end
            
        end

        % Value changed function: FindoptimalorderCheckBox
        function FindoptimalorderCheckBoxValueChanged(app, event)
            app.FilterOrderCheck = app.FindoptimalorderCheckBox.Value;
            
            % Set several filter orders for optimization (or not)
            if app.FilterOrderCheck == 1
                app.Filterorder2EditField.Visible = 1;
                app.toLabel.Visible = 1;
                app.filterordermax = app.Filterorder2EditField.Value;
                
                if app.filterordermax < app.filterorder
                    app.filterordermax = app.filterorder;
                    app.Filterorder2EditField.Value = app.filterordermax;
                end
                
            else
                app.Filterorder2EditField.Visible = 0;
                app.toLabel.Visible = 0;
                app.filterordermax = app.filterorder;
                
            end
            
        end

        % Value changed function: PixelDwellEditField
        function PixelDwellEditFieldValueChanged(app, event)
            app.PDT = app.PixelDwellEditField.Value;
            
            ImagingTimeUpdate(app)
            
        end

        % Value changed function: ImagingTimeEditField
        function ImagingTimeEditFieldValueChanged(app, event)
            app.ImagingTime = app.ImagingTimeEditField.Value;

            PDTUpdate(app)
            
        end

        % Value changed function: IncludeflybackCheckBox
        function IncludeflybackCheckBoxValueChanged(app, event)
            app.flybackcheck = app.IncludeflybackCheckBox.Value;
            
            % Set flyback on or off
            if app.flybackcheck == 0
                app.FlybackEditField.Enable = 0;
            else
                app.FlybackEditField.Enable = 1;
            end
            
            % Update Imaging or PDT input fields
            if app.TimeSwitch == '0'
                ImagingTimeUpdate(app)
            else
                PDTUpdate(app)
            end
            
        end

        % Value changed function: FlybackEditField
        function FlybackEditFieldValueChanged(app, event)
            app.flyback = app.FlybackEditField.Value;
            
            % Update Imaging or PDT input fields
            if app.TimeSwitch == '0'
                ImagingTimeUpdate(app)
            else
                PDTUpdate(app)
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged2(app, event)
            app.TimeSwitch = app.Switch.Value;
            % Switch: Imaging Time vs PDT
            
            % Activate Imaging Time or PDT field
            if app.OptimizeSwitch == 0
                if app.TimeSwitch == '1'
                    app.ImagingTimeEditField.Enable = 'on';
                    app.PixelDwellEditField.Enable = 'off';
                    
                else
                    app.ImagingTimeEditField.Enable = 'off';
                    app.PixelDwellEditField.Enable = 'on';
                    
                end
            end
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            % Optimize for tightly or sparsely spaced particles
            switch selectedButton.Text
                case "Dense / tightly packed particles"
                    app.choice = 1;
                case "Single particle"
                    app.choice = 0;
            end
                
        end

        % Value changed function: xpixelsEditField
        function xpixelsEditFieldValueChanged(app, event)
            app.xpixels = app.xpixelsEditField.Value;
            
            % Update the Field of View in the x direction
            app.xFOVumEditField.Value = app.pixelsize * app.xpixels;
            
            % Update Imaging Time or PDT
            if app.TimeSwitch == '0'
                ImagingTimeUpdate(app)
            else
                PDTUpdate(app)
            end
            
        end

        % Value changed function: ypixelsEditField
        function ypixelsEditFieldValueChanged(app, event)
            app.ypixels = app.ypixelsEditField.Value;
            
            % Update the Field of View in the y direction
            app.yFOVumEditField.Value = app.pixelsize * app.ypixels;
            
            % Update Imaging Time or PDT
            if app.TimeSwitch == '0'
                ImagingTimeUpdate(app)
            else
                PDTUpdate(app)
            end
            
        end

        % Value changed function: PixelsizeumEditField
        function PixelsizeumEditFieldValueChanged(app, event)
            app.pixelsize = app.PixelsizeumEditField.Value;
            
            % Update the Field of View in the x and y direction
            app.xFOVumEditField.Value = app.pixelsize * app.xpixels;
            app.yFOVumEditField.Value = app.pixelsize * app.ypixels;
            
        end

        % Value changed function: ObjectsizeumEditField
        function ObjectsizeumEditFieldValueChanged(app, event)
            app.objectsize = app.ObjectsizeumEditField.Value;
            
        end

        % Value changed function: CsignalEditField
        function CsignalEditFieldValueChanged(app, event)
            app.Csignal = app.CsignalEditField.Value;
            
        end

        % Value changed function: CthermalEditField
        function CthermalEditFieldValueChanged(app, event)
            app.Celec = app.CthermalEditField.Value;
            
        end

        % Value changed function: CshotEditField
        function CshotEditFieldValueChanged(app, event)
            app.Cshot = app.CshotEditField.Value;
            
        end

        % Value changed function: w01EditField
        function w01EditFieldValueChanged(app, event)
            app.w01x = app.w01EditField.Value;
            
        end

        % Value changed function: w02EditField
        function w02EditFieldValueChanged(app, event)
            app.w02x = app.w02EditField.Value;
            
        end

        % Value changed function: PowerSwitch
        function PowerSwitchValueChanged(app, event)
            Value = app.PowerSwitch.Value;
            % Switch: total power vs individual Probe and Carrier powers
            
            % Set power switch parameter 'on' or 'off'
            switch Value
                case "On"
                    app.PowerSwitchVal = 1;
                case "Off"
                    app.PowerSwitchVal = 0;
            end
            
            % Activate and inactivate Power field access
            if app.PowerSwitchVal
                app.CarrierpowermWEditField.Enable = 0;
                app.ProbepowermWEditField.Enable = 0;
                app.TotalpowermWEditField.Enable = 1;
            else
                app.CarrierpowermWEditField.Enable = 1;
                app.ProbepowermWEditField.Enable = 1;
                app.TotalpowermWEditField.Enable = 0;
            end
            
        end

        % Value changed function: CarrierpowermWEditField
        function CarrierpowermWEditFieldValueChanged(app, event)
            app.Carrier = app.CarrierpowermWEditField.Value;
            
        end

        % Value changed function: ProbepowermWEditField
        function ProbepowermWEditFieldValueChanged(app, event)
            app.Probe = app.ProbepowermWEditField.Value;
            
        end

        % Value changed function: CNREditField_2
        function CNREditField_2ValueChanged(app, event)
            app.CNR = app.CNREditField_2.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CalculateSettingsButton = uibutton(app.UIFigure, 'push');
            app.CalculateSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSettingsButtonPushed, true);
            app.CalculateSettingsButton.Text = 'Calculate Settings';
            app.ImagingTimesEditFieldLabel = uilabel(app.UIFigure);
            app.ImagingTimesEditFieldLabel.Text = 'Imaging Time  (s)';
            app.outputImagingTimes = uieditfield(app.UIFigure, 'numeric');
            app.outputImagingTimes.Editable = 'off';
            app.outputImagingTimes.Tooltip = {'Total imaging time.'};
            app.CarrierPoweronLabel = uilabel(app.UIFigure);
            app.CarrierPoweronLabel.Text = 'Carrier Power on';
            app.outputStokesPoweronSample = uieditfield(app.UIFigure, 'numeric');
            app.outputStokesPoweronSample.Editable = 'off';
            app.outputStokesPoweronSample.Tooltip = {'Total Carrier power on the sample.'};
            app.ProbePoweronLabel = uilabel(app.UIFigure);
            app.ProbePoweronLabel.Text = 'Probe Power on';
            app.outputPumppoweronSample = uieditfield(app.UIFigure, 'numeric');
            app.outputPumppoweronSample.Editable = 'off';
            app.outputPumppoweronSample.Tooltip = {'Total Probe power on the sample.'};
            app.FilterorderEditFieldLabel = uilabel(app.UIFigure);
            app.FilterorderEditFieldLabel.Text = 'Filter order';
            app.outputOrder = uieditfield(app.UIFigure, 'numeric');
            app.outputOrder.Editable = 'off';
            app.outputOrder.Tooltip = {'Filter order'};
            app.TimeconstantsLabel = uilabel(app.UIFigure);
            app.TimeconstantsLabel.Text = 'Time constant (s)';
            app.outputTC = uieditfield(app.UIFigure, 'numeric');
            app.outputTC.Editable = 'off';
            app.outputTC.Tooltip = {'Time constant.'};
            app.PixeldwelltimesLabel = uilabel(app.UIFigure);
            app.PixeldwelltimesLabel.Text = 'Pixel dwell time (s)';
            app.outputPixeldwelltime = uieditfield(app.UIFigure, 'numeric');
            app.outputPixeldwelltime.Editable = 'off';
            app.outputPixeldwelltime.Tooltip = {'Pixel dwell time.'};
            app.CalculatingLabel = uilabel(app.UIFigure);
            app.CalculatingLabel.Text = 'Calculating...';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Parameters1Tab = uitab(app.TabGroup);
            app.Parameters1Tab.Title = 'Parameters (1)';
            app.OptimizeButtonGroup = uibuttongroup(app.Parameters1Tab);
            app.OptimizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptimizeButtonGroupSelectionChanged, true);
            app.OptimizeButtonGroup.Tooltip = {'Specify the imaging time or the CNR'; ' so that the other can be optimized.'};
            app.OptimizeButtonGroup.Title = 'Optimize';
            app.ImagingTimeButton = uiradiobutton(app.OptimizeButtonGroup);
            app.ImagingTimeButton.Text = 'Imaging Time';
            app.ImagingTimeButton.Value = true;
            app.CNRButton = uiradiobutton(app.OptimizeButtonGroup);
            app.CNRButton.Text = 'CNR';
            app.ButtonGroup = uibuttongroup(app.OptimizeButtonGroup);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Tooltip = {'Sample choice: Optimize for a 1D periodic square wave sample (tightly packed particles). Optimize for 1D top-hat (single particle).'};
            app.DensetightlypackedparticlesButton = uiradiobutton(app.ButtonGroup);
            app.DensetightlypackedparticlesButton.Text = 'Dense / tightly packed particles';
            app.DensetightlypackedparticlesButton.Value = true;
            app.SingleparticleButton = uiradiobutton(app.ButtonGroup);
            app.SingleparticleButton.Text = 'Single particle';
            app.CNREditField_2Label = uilabel(app.Parameters1Tab);
            app.CNREditField_2Label.Text = 'CNR';
            app.CNREditField_2 = uieditfield(app.Parameters1Tab, 'numeric');
            app.CNREditField_2.Limits = [0 Inf];
            app.CNREditField_2.ValueChangedFcn = createCallbackFcn(app, @CNREditField_2ValueChanged, true);
            app.CNREditField_2.Tooltip = {'The Contrast to Noise Ratio which takes the maximal response values of the filter in account for a specific object size. CNR=(Signal_peak-Signal_background)/SQRT(2)*noise '};
            app.CNREditField_2.Value = 3;
            app.TimesLabel = uilabel(app.Parameters1Tab);
            app.TimesLabel.Text = 'Time (s)';
            app.ImagingTimeEditField = uieditfield(app.Parameters1Tab, 'numeric');
            app.ImagingTimeEditField.Limits = [0 Inf];
            app.ImagingTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ImagingTimeEditFieldValueChanged, true);
            app.ImagingTimeEditField.Enable = 'off';
            app.ImagingTimeEditField.Tooltip = {'Total time to collect 1 frame.'};
            app.ImagingTimeEditField.Value = 10;
            app.PixelDwellEditFieldLabel = uilabel(app.Parameters1Tab);
            app.PixelDwellEditFieldLabel.Text = 'Pixel Dwell';
            app.PixelDwellEditField = uieditfield(app.Parameters1Tab, 'numeric');
            app.PixelDwellEditField.Limits = [0 Inf];
            app.PixelDwellEditField.ValueChangedFcn = createCallbackFcn(app, @PixelDwellEditFieldValueChanged, true);
            app.PixelDwellEditField.Enable = 'off';
            app.PixelDwellEditField.Tooltip = {'Total time spent on 1 pixel.'};
            app.PixelDwellEditField.Value = 0.0006103515625;
            app.ImagingLabel = uilabel(app.Parameters1Tab);
            app.ImagingLabel.Text = 'Imaging';
            app.TimesLabel_2 = uilabel(app.Parameters1Tab);
            app.TimesLabel_2.Text = 'Time (s)';
            app.Switch = uiswitch(app.Parameters1Tab, 'slider');
            app.Switch.Items = {'1', '0'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged2, true);
            app.Switch.Value = '1';
            app.Panel = uipanel(app.Parameters1Tab);
            app.IncludeflybackCheckBox = uicheckbox(app.Panel);
            app.IncludeflybackCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeflybackCheckBoxValueChanged, true);
            app.IncludeflybackCheckBox.Tooltip = {'Include flyback when you want to account for the total imaging time'; ' including when the acquisition is not active.'};
            app.IncludeflybackCheckBox.Text = 'Include flyback?';
            app.FlybackEditFieldLabel = uilabel(app.Panel);
            app.FlybackEditFieldLabel.Text = 'Flyback (%)';
            app.FlybackEditField = uieditfield(app.Panel, 'numeric');
            app.FlybackEditField.Limits = [0 100];
            app.FlybackEditField.ValueChangedFcn = createCallbackFcn(app, @FlybackEditFieldValueChanged, true);
            app.FlybackEditField.Enable = 'off';
            app.FlybackEditField.Tooltip = {'The flyback adds a % of the default imaging time to the total imaging time.'};
            app.FlybackEditField.Value = 50;
            app.xpixelsEditFieldLabel = uilabel(app.Parameters1Tab);
            app.xpixelsEditFieldLabel.Text = 'x pixels';
            app.xpixelsEditField = uieditfield(app.Parameters1Tab, 'numeric');
            app.xpixelsEditField.Limits = [0 Inf];
            app.xpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @xpixelsEditFieldValueChanged, true);
            app.xpixelsEditField.Tooltip = {'Number of pixels in the x direction.'};
            app.xpixelsEditField.Value = 128;
            app.ypixelsEditFieldLabel = uilabel(app.Parameters1Tab);
            app.ypixelsEditFieldLabel.Text = 'y pixels';
            app.ypixelsEditField = uieditfield(app.Parameters1Tab, 'numeric');
            app.ypixelsEditField.Limits = [0 Inf];
            app.ypixelsEditField.ValueChangedFcn = createCallbackFcn(app, @ypixelsEditFieldValueChanged, true);
            app.ypixelsEditField.Tooltip = {'Number of pixels in the y direction.'};
            app.ypixelsEditField.Value = 128;
            app.xFOVumEditFieldLabel = uilabel(app.Parameters1Tab);
            app.xFOVumEditFieldLabel.Text = 'x FOV (um)';
            app.xFOVumEditField = uieditfield(app.Parameters1Tab, 'numeric');
            app.xFOVumEditField.Editable = 'off';
            app.xFOVumEditField.Tooltip = {'Field Of View in the x direction.'};
            app.xFOVumEditField.Value = 128;
            app.yFOVumEditFieldLabel = uilabel(app.Parameters1Tab);
            app.yFOVumEditFieldLabel.Text = 'y FOV (um)';
            app.yFOVumEditField = uieditfield(app.Parameters1Tab, 'numeric');
            app.yFOVumEditField.Editable = 'off';
            app.yFOVumEditField.Tooltip = {'Field Of View in the y direction.'};
            app.yFOVumEditField.Value = 128;
            app.ObjectsizeumEditFieldLabel = uilabel(app.Parameters1Tab);
            app.ObjectsizeumEditFieldLabel.Text = 'Object size (um)';
            app.ObjectsizeumEditField = uieditfield(app.Parameters1Tab, 'numeric');
            app.ObjectsizeumEditField.Limits = [0 Inf];
            app.ObjectsizeumEditField.ValueChangedFcn = createCallbackFcn(app, @ObjectsizeumEditFieldValueChanged, true);
            app.ObjectsizeumEditField.Tooltip = {'The size of the object for which the settings will be optimized.'};
            app.ObjectsizeumEditField.Value = 1;
            app.PixelsizeumEditFieldLabel = uilabel(app.Parameters1Tab);
            app.PixelsizeumEditFieldLabel.Text = 'Pixel size (um)';
            app.PixelsizeumEditField = uieditfield(app.Parameters1Tab, 'numeric');
            app.PixelsizeumEditField.Limits = [0 Inf];
            app.PixelsizeumEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizeumEditFieldValueChanged, true);
            app.PixelsizeumEditField.Tooltip = {'Pixel size in um.'};
            app.PixelsizeumEditField.Value = 1;
            app.Parameters2Tab = uitab(app.TabGroup);
            app.Parameters2Tab.Title = 'Parameters (2)';
            app.Filterorder2EditField = uieditfield(app.Parameters2Tab, 'numeric');
            app.Filterorder2EditField.Limits = [1 Inf];
            app.Filterorder2EditField.RoundFractionalValues = 'on';
            app.Filterorder2EditField.ValueChangedFcn = createCallbackFcn(app, @Filterorder2EditFieldValueChanged, true);
            app.Filterorder2EditField.Tooltip = {'The upper value of the filter order range for which the best will be optimized. The lower value of the range is given by the field to the left.'};
            app.Filterorder2EditField.Value = 8;
            app.toLabel = uilabel(app.Parameters2Tab);
            app.toLabel.Text = 'to';
            app.FindoptimalorderCheckBox = uicheckbox(app.Parameters2Tab);
            app.FindoptimalorderCheckBox.ValueChangedFcn = createCallbackFcn(app, @FindoptimalorderCheckBoxValueChanged, true);
            app.FindoptimalorderCheckBox.Tooltip = {'Find optimal filter order for a specified range.'};
            app.FindoptimalorderCheckBox.Text = 'Find optimal order?';
            app.FilterorderEditField_2Label = uilabel(app.Parameters2Tab);
            app.FilterorderEditField_2Label.Text = 'Filter order';
            app.FilterorderEditField_2 = uieditfield(app.Parameters2Tab, 'numeric');
            app.FilterorderEditField_2.Limits = [1 Inf];
            app.FilterorderEditField_2.RoundFractionalValues = 'on';
            app.FilterorderEditField_2.ValueChangedFcn = createCallbackFcn(app, @FilterorderEditField_2ValueChanged, true);
            app.FilterorderEditField_2.Tooltip = {'Filter order of the digital RC-filter of the Lock-In Amplifier.'};
            app.FilterorderEditField_2.Value = 1;
            app.Panel_2 = uipanel(app.Parameters2Tab);
            app.CarrierpowermWEditFieldLabel = uilabel(app.Panel_2);
            app.CarrierpowermWEditFieldLabel.Text = 'Carrier power (mW)';
            app.CarrierpowermWEditField = uieditfield(app.Panel_2, 'numeric');
            app.CarrierpowermWEditField.Limits = [0 Inf];
            app.CarrierpowermWEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierpowermWEditFieldValueChanged, true);
            app.CarrierpowermWEditField.Enable = 'off';
            app.CarrierpowermWEditField.Tooltip = {'Total average power of the Carrier beam(s).'};
            app.CarrierpowermWEditField.Value = 28;
            app.ProbepowermWEditFieldLabel = uilabel(app.Panel_2);
            app.ProbepowermWEditFieldLabel.Tooltip = {'Average probe power.'};
            app.ProbepowermWEditFieldLabel.Text = 'Probe power (mW)';
            app.ProbepowermWEditField = uieditfield(app.Panel_2, 'numeric');
            app.ProbepowermWEditField.Limits = [0 Inf];
            app.ProbepowermWEditField.ValueChangedFcn = createCallbackFcn(app, @ProbepowermWEditFieldValueChanged, true);
            app.ProbepowermWEditField.Enable = 'off';
            app.ProbepowermWEditField.Tooltip = {'Total average power of the probe beam(s).'};
            app.ProbepowermWEditField.Value = 14;
            app.LaserpoweronsamplePanel = uipanel(app.Parameters2Tab);
            app.LaserpoweronsamplePanel.Title = 'Laser power on sample';
            app.TotalpowermWEditFieldLabel = uilabel(app.LaserpoweronsamplePanel);
            app.TotalpowermWEditFieldLabel.Text = 'Total power (mW)';
            app.TotalpowermWEditField = uieditfield(app.LaserpoweronsamplePanel, 'numeric');
            app.TotalpowermWEditField.Limits = [0 Inf];
            app.TotalpowermWEditField.ValueChangedFcn = createCallbackFcn(app, @TotalpowermWEditFieldValueChanged, true);
            app.TotalpowermWEditField.Tooltip = {'Total average power of both beams combined.'};
            app.TotalpowermWEditField.Value = 20;
            app.PowerSwitchLabel = uilabel(app.Parameters2Tab);
            app.PowerSwitchLabel.Text = 'PowerSwitch';
            app.PowerSwitch = uiswitch(app.Parameters2Tab, 'slider');
            app.PowerSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerSwitchValueChanged, true);
            app.PowerSwitch.Value = 'On';
            app.SetupparametersTab = uitab(app.TabGroup);
            app.SetupparametersTab.Title = 'Setup parameters';
            app.Beam1Panel = uipanel(app.SetupparametersTab);
            app.Beam1Panel.Title = 'Beam 1';
            app.w01EditFieldLabel = uilabel(app.Beam1Panel);
            app.w01EditFieldLabel.Text = 'w01';
            app.w01EditField = uieditfield(app.Beam1Panel, 'numeric');
            app.w01EditField.Limits = [0 Inf];
            app.w01EditField.ValueChangedFcn = createCallbackFcn(app, @w01EditFieldValueChanged, true);
            app.w01EditField.Tooltip = {'Beam 1/e2 radius of a Gaussian beam.'};
            app.w01EditField.Value = 0.44574;
            app.Beam2Panel = uipanel(app.SetupparametersTab);
            app.Beam2Panel.Title = 'Beam 2';
            app.w02EditFieldLabel = uilabel(app.Beam2Panel);
            app.w02EditFieldLabel.Text = 'w02';
            app.w02EditField = uieditfield(app.Beam2Panel, 'numeric');
            app.w02EditField.Limits = [0 Inf];
            app.w02EditField.ValueChangedFcn = createCallbackFcn(app, @w02EditFieldValueChanged, true);
            app.w02EditField.Tooltip = {'Beam 1/e2 radius of a Gaussian beam.'};
            app.w02EditField.Value = 0.51499;
            app.CsignalEditFieldLabel = uilabel(app.SetupparametersTab);
            app.CsignalEditFieldLabel.Text = 'Csignal';
            app.CsignalEditField = uieditfield(app.SetupparametersTab, 'numeric');
            app.CsignalEditField.Limits = [0 Inf];
            app.CsignalEditField.ValueChangedFcn = createCallbackFcn(app, @CsignalEditFieldValueChanged, true);
            app.CsignalEditField.Tooltip = {'SRS signal coefficient.'};
            app.CsignalEditField.Value = 3e-06;
            app.CthermalEditFieldLabel = uilabel(app.SetupparametersTab);
            app.CthermalEditFieldLabel.Text = 'Cthermal';
            app.CthermalEditField = uieditfield(app.SetupparametersTab, 'numeric');
            app.CthermalEditField.Limits = [0 Inf];
            app.CthermalEditField.ValueChangedFcn = createCallbackFcn(app, @CthermalEditFieldValueChanged, true);
            app.CthermalEditField.Tooltip = {'Thermal noise coefficient.'};
            app.CthermalEditField.Value = 1.518e-07;
            app.CshotEditFieldLabel = uilabel(app.SetupparametersTab);
            app.CshotEditFieldLabel.Text = 'Cshot';
            app.CshotEditField = uieditfield(app.SetupparametersTab, 'numeric');
            app.CshotEditField.Limits = [0 Inf];
            app.CshotEditField.ValueChangedFcn = createCallbackFcn(app, @CshotEditFieldValueChanged, true);
            app.CshotEditField.Tooltip = {'Shot noise coefficient.'};
            app.CshotEditField.Value = 1.364e-07;
            app.Label_2 = uilabel(app.SetupparametersTab);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.SetupparametersTab);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.SetupparametersTab);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.SetupparametersTab);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.SetupparametersTab);
            app.Label_6.Text = '';
            app.SamplemWLabel = uilabel(app.UIFigure);
            app.SamplemWLabel.Text = ' Sample (mW)';
            app.SamplemWLabel_2 = uilabel(app.UIFigure);
            app.SamplemWLabel_2.Text = ' Sample (mW)';
            app.OutputparametersLabel = uilabel(app.UIFigure);
            app.OutputparametersLabel.Text = 'Output parameters';
            app.CNREditFieldLabel = uilabel(app.UIFigure);
            app.CNREditFieldLabel.Text = 'CNR';
            app.CNREditField = uieditfield(app.UIFigure, 'numeric');
            app.CNREditField.Editable = 'off';
            app.CNREditField.Tooltip = {'The Contrast to Noise Ratio which takes the maximal response values of the filter in account for a specific object size. CNR=(Signal_peak-Signal_background)/SQRT(2)*noise '};
        end
    end

    methods (Access = public)

        % Construct app
        function app = OptimalSettingsInterface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end